classdef ZeroDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        v10Label                 matlab.ui.control.Label
        IMUPanel                 matlab.ui.container.Panel
        sLabel                   matlab.ui.control.Label
        sEditField               matlab.ui.control.NumericEditField
        XRMSEditFieldLabel       matlab.ui.control.Label
        XRMSEditField            matlab.ui.control.NumericEditField
        gEditFieldLabel          matlab.ui.control.Label
        gEditField               matlab.ui.control.NumericEditField
        gRMSEditFieldLabel       matlab.ui.control.Label
        gRMSEditField            matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        Button_4                 matlab.ui.control.Button
        XRMSEditField_2Label     matlab.ui.control.Label
        XRMSEditField_2          matlab.ui.control.NumericEditField
        gmeanEditFieldLabel      matlab.ui.control.Label
        gmeanEditField           matlab.ui.control.NumericEditField
        gRMSEditField_2Label     matlab.ui.control.Label
        gRMSEditField_2          matlab.ui.control.NumericEditField
        sLabel_2                 matlab.ui.control.Label
        sEditField_2             matlab.ui.control.NumericEditField
        Panel                    matlab.ui.container.Panel
        Button                   matlab.ui.control.Button
        EditField_2              matlab.ui.control.EditField
        CheckBox                 matlab.ui.control.CheckBox
        Label_2                  matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        MPU_AL1R2ADI_BL3R4Label  matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        GyroButton               matlab.ui.control.RadioButton
        AccelButton              matlab.ui.control.RadioButton
        Gyro_And_AccelButton     matlab.ui.control.RadioButton
        Button_2                 matlab.ui.control.Button
        Panel_2                  matlab.ui.container.Panel
        GyroCheckBox             matlab.ui.control.CheckBox
        AccelCheckBox            matlab.ui.control.CheckBox
        Gyro_And_AccelCheckBox   matlab.ui.control.CheckBox
        Button_3                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        imuDataPath    string
        imuDataName    string        
        IMUData        double
        IMUData_isOK    double
        
        % 特性参数相关
        Fs                      double  %采样频率
        timeUsedStart           double  %用于提取器件特性参数的起始时间  单位s
        timeUsedEnd             double
        rmsXGyro_threshold      double  %x轴陀螺输出方差判断阈值的倍数 
        rmsXGyro                double  %X轴陀螺输出的均方差
        meanAccel_threshold     double  %加计 g 测量幅值 判断阈值的倍数
        meanAccel               double  %加计 测量g 的均值
        rmsAccel_threshold      double  %加计 测量g 的均方差 判断阈值的倍数
        rmsAccel                double  %加计 测量g 的均方差        
        sizeWindows             double  %步态检测 窗口长度        
        paraSet_isOK            double
        
        % 步态检测结果
        FootState_Gyro          double
        FootState_Gyro_isOK     double   
        FootState_Accel          double
        FootState_Accel_isOK     double     
        FootState_GyroAccel          double
        FootState_GyroAccel_isOK     double 
        
    end
    
    methods (Access = public)
        % 步态检测 陀螺
        function results = ZeroDetection_Gyro(app)
            L = length(app.IMUData);
            app.FootState_Gyro = zeros(L,2);     % 步态状态，第一列时间，第二列状态：0运动，1静止
            app.FootState_Gyro(:,1) = app.IMUData(:,1);
%             for i = app.sizeWindows : L
                % 第一次判断  SHOE 方法 失败！
%                 if i == app.sizeWindows
%                    tmpXGyro = sum(app.IMUData(1:app.sizeWindows,5).^2);
%                    tmp = tmpXGyro/app.sizeWindows/(deg2rad(0.1)^2);
%                    if tmp <= 10^4.6
%                        app.FootState_Gyro(1:app.sizeWindows,2) = 1;
%                    else
%                        app.FootState_Gyro(1:app.sizeWindows,2) = 0;
%                    end
%                 else
%                    tmpXGyro = sum(app.IMUData(i-app.sizeWindows+1:i,5).^2);
%                    tmp = tmpXGyro/app.sizeWindows/(deg2rad(0.1)^2);
%                    if tmp <= 10^4.6
%                        app.FootState_Gyro(i,2) = 1;
%                    else
%                        app.FootState_Gyro(i,2) = 0;
%                    end
%                 end                      

            numSize = app.sizeWindows;
            L_KF = fix(L/numSize);            
            RmsGyro = zeros(L_KF,2);
            for i = 1:L_KF
                %判断当前这个窗口内的数据 是否 平稳   x轴陀螺均方差
                tmpRmsGyro = sqrt(var(app.IMUData((i-1)*numSize+1:i*numSize,5)));
                RmsGyro(i,2) = tmpRmsGyro;
                if (tmpRmsGyro <= (app.rmsXGyro*app.rmsXGyro_threshold))
                    app.FootState_Gyro((i-1)*numSize+1:i*numSize,2) = 1;                    
                end                
                RmsGyro(i,1) = i*numSize;
            end
            
            app.FootState_Gyro_isOK = 1;
            app.GyroCheckBox.Enable = 1;      
            results = 1;
            msgbox('零速检测完成！');
            
            % 把结果绘制出来
            %   幅值判断情况
            figure;
            plot((1:L),app.IMUData(1:L,5),'Color','k','DisplayName','xGyro','LineStyle',"-.");  % 原始 xGyro数据
            hold on; plot((1:L),ones(L,1).*(app.rmsXGyro*app.rmsXGyro_threshold),'Color','b','DisplayName','rms阈值');  %幅值判断边界
            hold on; plot(RmsGyro(:,1),RmsGyro(:,2),'Color','m','DisplayName','rms_8','LineWidth',2); % 计算的均方差
            hold on; plot((1:L),app.FootState_Gyro(:,2).*1,'Color','r','DisplayName','state');  %判断结果
            title('X陀螺方差  判断');legend;

                % 第一次判断  重写
%                 if i == app.sizeWindows
%                    tmpXGyro = app.IMUData(1:app.sizeWindows,5);
%                    tmp = sqrt(var(tmpXGyro));
%                    if tmp <= app.rmsXGyro*app.rmsXGyro_threshold
%                        app.FootState_Gyro(1:app.sizeWindows,2) = 1;
%                    else
%                        app.FootState_Gyro(1:app.sizeWindows,2) = 0;
%                    end
%                 else
%                    tmpXGyro = app.IMUData(i-app.sizeWindows+1:i,5);
%                    tmp = sqrt(var(tmpXGyro));
%                    if tmp <= app.rmsXGyro*app.rmsXGyro_threshold
%                        app.FootState_Gyro(i,2) = 1;
%                    else
%                        app.FootState_Gyro(i,2) = 0;
%                    end
%                 end                               
%             end

        end
        
        % 步态检测 利用加速度计的数据检测
        %   这里按照时间间隔来判断，而不是每一个点都判断，周期为KF滤波的整数倍，便于应用
        function results = ZeroDetection_Accel(app)
            L = length(app.IMUData);            
            app.FootState_Accel = zeros(L,2);     % 步态状态，第一列时间，第二列状态：0运动，1静止
            app.FootState_Accel(:,1) = app.IMUData(:,1);
            amplAccel = sqrt(app.IMUData(:,2).^2+app.IMUData(:,3).^2+app.IMUData(:,4).^2);
            numSize = app.sizeWindows;

%-------------------------间隔计算方法-------------------------------------- 
%             L_KF = fix(L/numSize);
%             MeanAccel = zeros(L_KF,2);
%             RmsAccel = zeros(L_KF,2);
%             for i = 1:L_KF
%                 %判断当前这个窗口内的数据 是否 平稳
%                 % （1）幅值均值 
%                 tmpMeanAccel = mean(amplAccel((i-1)*numSize+1:i*numSize,1)); 
%                 MeanAccel(i,2) = tmpMeanAccel;
%                 % （2）幅值 均方差 
%                 tmpRmsAccel = sqrt(var(amplAccel((i-1)*numSize+1:i*numSize,1)));
%                 RmsAccel(i,2) = tmpRmsAccel;
%                 %if (abs(tmpMeanAccel-app.meanAccel) <= app.meanAccel_threshold) && (tmpRmsAccel <= app.rmsAccel*app.rmsAccel_threshold)
%                 %if (abs(tmpMeanAccel-app.meanAccel) <= app.meanAccel_threshold) 
%                 if (tmpRmsAccel <= app.rmsAccel*app.rmsAccel_threshold)
%                     app.FootState_Accel((i-1)*numSize+1:i*numSize,2) = 1;                    
%                 end                
%                 MeanAccel(i,1) = i*numSize;
%                 RmsAccel(i,1) = i*numSize;
%             end
%             % 把结果绘制出来
%             %   幅值判断情况
%             figure;
%             plot((1:L),amplAccel,'Color','k','DisplayName','g','LineStyle',"-.");  %原始幅值
%             hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1+app.meanAccel_threshold)),'Color','b','DisplayName','mean阈值');  %幅值判断边界
%             hold on; plot(MeanAccel(:,1),MeanAccel(:,2),'Color','m','DisplayName','mean_8','LineWidth',2); % 计算的平均幅值
%             hold on; plot((1:L),app.FootState_Accel(:,2).*1.2,'Color','r','DisplayName','state');  %判断结果
%             title('加计幅值  判断');ylim([0,1.5]); legend;
            
%             %  均方差判断情况
%             figure;
%             plot((1:L),amplAccel-1,'Color','k','DisplayName','g-1','LineStyle','-.','Marker','*');  %原始幅值
%             hold on; plot((1:L),ones(L,1).*(app.rmsAccel*app.rmsAccel_threshold),'Color','b','DisplayName','rms阈值','LineWidth',2);  %均方差判断边界
%             hold on; plot(RmsAccel(:,1),RmsAccel(:,2),'Color','m','DisplayName','rms_8','LineWidth',2); % 计算的均方值
%             hold on; plot((1:L),app.FootState_Accel(:,2).*1,'Color','r','DisplayName','state');  %判断结果
%             title('加计方差  判断');legend;
%==========================================================================

%-------------------------滑动计算法----------------------------------------            
            MeanAccel = zeros(L,2);
            RmsAccel = zeros(L,2);
            for i = numSize:L
                if i == numSize
                    tmpMeanAccel = mean(amplAccel(1:numSize,1));
                    MeanAccel(1:numSize,2) = tmpMeanAccel;
                    tmpRmsAccel = sqrt(var(amplAccel(1:numSize,1)));
                    RmsAccel(1:numSize,2) = app.rmsAccel;     %起始均方差 默认不计算，采用系统初始均方差
                    MeanAccel(1:i,1) = (1:i)';
                    RmsAccel(1:i,1) = (1:i)';
                else
                    tmpMeanAccel = mean(amplAccel(i-numSize+1:i,1));
                    MeanAccel(i,2) = tmpMeanAccel;
                    tmpRmsAccel = sqrt(var(amplAccel(i-numSize+1:i,1)));
                    RmsAccel(i,2) = tmpRmsAccel;
                    MeanAccel(i,1) = i;
                    RmsAccel(i,1) = i;
                end
                
                % 先用加速度幅值进行第一次筛选
                if (tmpMeanAccel >= (app.meanAccel*(1-app.meanAccel_threshold))) && ...
                        (tmpMeanAccel <= (app.meanAccel*(1+app.meanAccel_threshold)))
                    % 再用加速度均方差 做 第二次筛选
                    if tmpRmsAccel <= app.rmsAccel*app.rmsAccel_threshold
                        if i == numSize
                            app.FootState_Accel(1:i,2) = 1;   
                        else
                            app.FootState_Accel(i,2) = 1;                            
                        end
                    end
                end
            end
            
            % 把结果绘制出来
            %   幅值判断情况
            figure;
            plot((1:L),amplAccel,'Color','k','DisplayName','g','LineStyle',"-.");  %原始幅值
            hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1-app.meanAccel_threshold)),'Color','b');
            hold on; plot((1:L),ones(L,1).*(app.meanAccel*(1+app.meanAccel_threshold)),'Color','b','DisplayName','mean阈值');  %幅值判断边界
            hold on; plot(MeanAccel(:,1),MeanAccel(:,2),'Color','m','DisplayName','mean_8','LineWidth',2); % 计算的平均幅值
            hold on; plot((1:L),app.FootState_Accel(:,2).*1.2,'Color','r','DisplayName','state');  %判断结果
            title('加计幅值  判断');ylim([0,1.5]); legend;            
            %  均方差判断情况
            figure;
            plot((1:L),app.IMUData(1:L,4)-1,'Color','k','DisplayName','zAccel','LineStyle','-.','Marker','*');  %原始幅值
            hold on; plot((1:L),ones(L,1).*(app.rmsAccel*app.rmsAccel_threshold),'Color','b','DisplayName','rms阈值');  %均方差判断边界
            hold on; plot(RmsAccel(:,1),RmsAccel(:,2),'Color','m','DisplayName','rms_8','LineWidth',2); % 计算的均方值
            hold on; plot((1:L),app.FootState_Accel(:,2).*1,'Color','r','DisplayName','state');  %判断结果
            title('加计方差  判断');legend;            


            app.FootState_Accel_isOK = 1;
            results = 1;
        end
        
        % 利用陀螺输出的方差 和 加计 的幅值 方差 共同判断
        function results = ZeroDetection_GyroAccel(app)
            L = length(app.IMUData);
            app.FootState_GyroAccel = zeros(L,2);     % 步态状态，第一列时间，第二列状态：0运动，1静止
            app.FootState_GyroAccel(:,1) = app.IMUData(:,1);    
            numSize = app.sizeWindows;
            L_KF = fix(L/numSize);            
            RmsGyro = zeros(L_KF,2);
            amplAccel = sqrt(app.IMUData(:,2).^2+app.IMUData(:,3).^2+app.IMUData(:,4).^2);
            MeanAccel = zeros(L_KF,2);
            RmsAccel = zeros(L_KF,2);   
            for i = 1:L_KF                
                % (1) x轴陀螺均方差
                tmpRmsGyro = sqrt(var(app.IMUData((i-1)*numSize+1:i*numSize,5)));
                RmsGyro(i,2) = tmpRmsGyro;
                % (2) 加计幅值均值 
                tmpMeanAccel = mean(amplAccel((i-1)*numSize+1:i*numSize,1)); 
                MeanAccel(i,2) = tmpMeanAccel;
                % (3) 加计幅值 均方差 
                tmpRmsAccel = sqrt(var(amplAccel((i-1)*numSize+1:i*numSize,1)));
                RmsAccel(i,2) = tmpRmsAccel;                
                
                % 综合判断
%                 if (tmpRmsGyro <= (app.rmsXGyro*app.rmsXGyro_threshold)) &&... 
%                 (abs(tmpMeanAccel-app.meanAccel) <= app.meanAccel_threshold) &&...
%                 (tmpRmsAccel <= app.rmsAccel*app.rmsAccel_threshold)
                if (tmpRmsGyro <= (app.rmsXGyro*app.rmsXGyro_threshold)) &&... 
                (tmpRmsAccel <= app.rmsAccel*app.rmsAccel_threshold)
                    app.FootState_GyroAccel((i-1)*numSize+1:i*numSize,2) = 1;                    
                end                
                
                RmsGyro(i,1) = i*numSize;
                MeanAccel(i,1) = i*numSize;
                RmsAccel(i,1) = i*numSize;                
            end
            
            app.FootState_Gyro_isOK = 1;
            app.GyroCheckBox.Enable = 1;      
            results = 1;
            msgbox('零速检测完成！');            
            
            % 绘制结果
            figure;
            plot((1:L),app.IMUData(1:L,5),'Color','k','DisplayName','xGyro','LineStyle',"-.");  % 原始 xGyro数据
            hold on; plot((1:L),amplAccel(:,1)-1,'Color','k','DisplayName','g','LineStyle',"-");  % 原始 g数据
            hold on; plot((1:L),app.FootState_GyroAccel(:,2).*1,'Color','r','DisplayName','state');  %判断结果
            title('综合判断 X陀螺方差+g加计');legend;   
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timeUsedStart = app.sEditField.Value;
            app.timeUsedEnd = app.sEditField_2.Value;
            app.rmsXGyro_threshold = app.XRMSEditField.Value;
            app.rmsXGyro = app.XRMSEditField_2.Value;
            app.meanAccel_threshold = app.gEditField.Value;
            app.meanAccel = app.gmeanEditField.Value;
            app.rmsAccel_threshold = app.gRMSEditField.Value;
            app.rmsAccel = app.gRMSEditField_2.Value;
            app.sizeWindows = app.EditField.Value;
            app.Fs = 200;
            app.IMUData_isOK = 0;
            app.paraSet_isOK = 0;
            app.FootState_Gyro_isOK = 0;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [IMU_FileName,IMU_FilePath] = uigetfile('*.mat');
            if isequal(IMU_FileName,0)
               msgbox('警告：未选中IMU数据！！！');
               app.IMUData_isOK = 0;
            else               
               app.imuDataPath = IMU_FilePath;
               app.imuDataName = IMU_FileName;
               app.EditField_2.Value = fullfile(IMU_FilePath,IMU_FileName);
               load(fullfile(IMU_FilePath,IMU_FileName));
               if exist('IMU') ~= 1
                   msgbox('警告：IMU数据为空！');
                   app.IMUData_isOK = 0;
               else
                   if app.CheckBox.Value == 0
                      app.IMUData = IMU;         
                      app.IMUData_isOK = 1;
                   else
                      % 进行非正交校正 
                      switch app.EditField_3.Value
                          % ADI UB R                          
                          case 4
                              app.IMUData = IMU;   
                              M_ADI_UB_R = [0.999844387	0.01471722 	0.002556173
                                            -0.01469017	0.99978481 	0.00053635
                                            -0.01245593	-0.00133620 	1.000481267];
                              L = length(app.IMUData);
                              for i=1:L
                                  tmp = M_ADI_UB_R*[app.IMUData(i,2);app.IMUData(i,3);app.IMUData(i,4)];
                                  app.IMUData(i,2) = tmp(1,1);
                                  app.IMUData(i,3) = tmp(2,1);
                                  app.IMUData(i,4) = tmp(3,1);
                              end
                              app.IMUData_isOK = 1;
                          otherwise
                              msgbox('器件类型选择错误！');
                              app.IMUData_isOK = 0;
                              return;
                      end
                   end
               end
            end                 
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if isempty(app.IMUData) == 1
                msgbox('警告：IMU数据为空！');
                return;
            end            
            
            %计算 所需的时间长度
            L_IMU = length(app.IMUData);
            L = (app.timeUsedEnd - app.timeUsedStart) * app.Fs;
            if (L <= 0) || (L > L_IMU)
                msgbox('参数提取时间设置错误！');
                return;
            end
            tpNumStart = app.timeUsedStart*app.Fs;
            if tpNumStart == 0
                tpNumStart = 1;
            end
            tpNumEnd = app.timeUsedEnd*app.Fs;
            
            L = tpNumEnd - tpNumStart + 1;
            
            %提取 陀螺 和 加计 原始数据
            xGyroData = app.IMUData(tpNumStart:tpNumEnd,5);
            xAccelData = app.IMUData(tpNumStart:tpNumEnd,2);
            yAccelData = app.IMUData(tpNumStart:tpNumEnd,3);
            zAccelData = app.IMUData(tpNumStart:tpNumEnd,4);
            
            %计算相应的特征参数
            % x陀螺输出均方差
            temp = sqrt(var(xGyroData));
            app.XRMSEditField_2.Value = temp;
            app.rmsXGyro = temp;
            
            % 加计幅值 均值 计算
            amplAccel = sqrt(xAccelData.^2+yAccelData.^2+zAccelData.^2);
            temp = mean(amplAccel);
            app.meanAccel = temp;
            app.gmeanEditField.Value = temp;
            
            % 加计幅值方差计算
            temp = sqrt(var(amplAccel));
            app.rmsAccel = temp;
            app.gRMSEditField_2.Value = temp;
            
            app.paraSet_isOK = 1;
            msgbox('参数提取完成！');
        end

        % Value changed function: sEditField
        function sEditFieldValueChanged(app, event)
            app.timeUsedStart = app.sEditField.Value;             
        end

        % Value changed function: XRMSEditField
        function XRMSEditFieldValueChanged(app, event)
            app.rmsXGyro_threshold = app.XRMSEditField.Value;            
        end

        % Value changed function: gEditField
        function gEditFieldValueChanged(app, event)
            app.meanAccel_threshold = app.gEditField.Value;            
        end

        % Value changed function: gRMSEditField
        function gRMSEditFieldValueChanged(app, event)
            app.rmsAccel_threshold = app.gRMSEditField.Value;            
        end

        % Value changed function: XRMSEditField_2
        function XRMSEditField_2ValueChanged(app, event)
            app.rmsXGyro = app.XRMSEditField_2.Value;            
        end

        % Value changed function: gmeanEditField
        function gmeanEditFieldValueChanged(app, event)
            app.meanAccel = app.gmeanEditField.Value;            
        end

        % Value changed function: gRMSEditField_2
        function gRMSEditField_2ValueChanged(app, event)
            app.rmsAccel = app.gRMSEditField_2.Value;            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.sizeWindows = app.EditField.Value;            
        end

        % Value changed function: sEditField_2
        function sEditField_2ValueChanged(app, event)
            app.timeUsedEnd = app.sEditField_2.Value;            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if (app.IMUData_isOK == 0) || (app.paraSet_isOK == 0)
                msgbox('前面参数设置有问题！')
                return;
            end
            
            if app.GyroButton.Value == 1
                results = ZeroDetection_Gyro(app);
                return;
            end
            if app.AccelButton.Value == 1
                results = ZeroDetection_Accel(app);
                return;
            end           
            if app.Gyro_And_AccelButton.Value == 1
                results = ZeroDetection_GyroAccel(app);
                return;                
            end
            
            
            msgbox('检测算法选择错误！');
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 绘制零速检测结果数据
            tmp = sqrt(app.IMUData(:,2).^2+app.IMUData(:,3).^2+app.IMUData(:,4).^2) - 1;
            figure;
            plot(app.IMUData(:,5).*50,'Color','g','DisplayName','xGyro*50');
            hold on;
            plot(tmp.*30,'Color','m','DisplayName','(g-1)*30');
            if app.GyroCheckBox.Value == 1
                hold on;
                plot(app.FootState_Gyro(:,2).*30,'Color','k','DisplayName','xGyro_state');
            end
            legend;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = {'单兵导航_步态识别_v1.0'; ''};
            app.IMUPanel = uipanel(app.UIFigure);
            app.IMUPanel.Title = '2.IMU步态识别参数设定';
            app.sLabel = uilabel(app.IMUPanel);
            app.sLabel.Text = '参数提取开始(s)';
            app.sEditField = uieditfield(app.IMUPanel, 'numeric');
            app.sEditField.ValueChangedFcn = createCallbackFcn(app, @sEditFieldValueChanged, true);
            app.XRMSEditFieldLabel = uilabel(app.IMUPanel);
            app.XRMSEditFieldLabel.Text = 'X陀螺RMS倍数范围';
            app.XRMSEditField = uieditfield(app.IMUPanel, 'numeric');
            app.XRMSEditField.ValueChangedFcn = createCallbackFcn(app, @XRMSEditFieldValueChanged, true);
            app.XRMSEditField.Value = 3;
            app.gEditFieldLabel = uilabel(app.IMUPanel);
            app.gEditFieldLabel.Text = '加计g幅值倍数范围';
            app.gEditField = uieditfield(app.IMUPanel, 'numeric');
            app.gEditField.ValueChangedFcn = createCallbackFcn(app, @gEditFieldValueChanged, true);
            app.gEditField.Value = 0.03;
            app.gRMSEditFieldLabel = uilabel(app.IMUPanel);
            app.gRMSEditFieldLabel.Text = '加计gRMS倍数范围';
            app.gRMSEditField = uieditfield(app.IMUPanel, 'numeric');
            app.gRMSEditField.ValueChangedFcn = createCallbackFcn(app, @gRMSEditFieldValueChanged, true);
            app.gRMSEditField.Value = 8;
            app.Label = uilabel(app.IMUPanel);
            app.Label.Text = '识别时间窗长度';
            app.EditField = uieditfield(app.IMUPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 8;
            app.Button_4 = uibutton(app.IMUPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数提取';
            app.XRMSEditField_2Label = uilabel(app.IMUPanel);
            app.XRMSEditField_2Label.Text = 'X陀螺RMS';
            app.XRMSEditField_2 = uieditfield(app.IMUPanel, 'numeric');
            app.XRMSEditField_2.ValueChangedFcn = createCallbackFcn(app, @XRMSEditField_2ValueChanged, true);
            app.gmeanEditFieldLabel = uilabel(app.IMUPanel);
            app.gmeanEditFieldLabel.Text = {'加计g幅值mean'; ''};
            app.gmeanEditField = uieditfield(app.IMUPanel, 'numeric');
            app.gmeanEditField.ValueChangedFcn = createCallbackFcn(app, @gmeanEditFieldValueChanged, true);
            app.gRMSEditField_2Label = uilabel(app.IMUPanel);
            app.gRMSEditField_2Label.Text = {'加计g幅值RMS'; ''};
            app.gRMSEditField_2 = uieditfield(app.IMUPanel, 'numeric');
            app.gRMSEditField_2.ValueChangedFcn = createCallbackFcn(app, @gRMSEditField_2ValueChanged, true);
            app.sLabel_2 = uilabel(app.IMUPanel);
            app.sLabel_2.Text = '参数提取结束(s)';
            app.sEditField_2 = uieditfield(app.IMUPanel, 'numeric');
            app.sEditField_2.ValueChangedFcn = createCallbackFcn(app, @sEditField_2ValueChanged, true);
            app.sEditField_2.Value = 10;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '1.读取数据';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = '非正交校正';
            app.CheckBox.Value = true;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '器件';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Value = 4;
            app.MPU_AL1R2ADI_BL3R4Label = uilabel(app.Panel);
            app.MPU_AL1R2ADI_BL3R4Label.Text = {'MPU_A：L 1  R 2'; 'ADI_B：  L 3 R 4'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '3.步态识别方法选择';
            app.GyroButton = uiradiobutton(app.ButtonGroup);
            app.GyroButton.Text = 'Gyro';
            app.GyroButton.Value = true;
            app.AccelButton = uiradiobutton(app.ButtonGroup);
            app.AccelButton.Text = {'Accel'; ''};
            app.Gyro_And_AccelButton = uiradiobutton(app.ButtonGroup);
            app.Gyro_And_AccelButton.Text = 'Gyro_And_Accel';
            app.Button_2 = uibutton(app.ButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '识别计算';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '4.绘制结果';
            app.GyroCheckBox = uicheckbox(app.Panel_2);
            app.GyroCheckBox.Enable = 'off';
            app.GyroCheckBox.Text = 'Gyro';
            app.AccelCheckBox = uicheckbox(app.Panel_2);
            app.AccelCheckBox.Enable = 'off';
            app.AccelCheckBox.Text = 'Accel';
            app.Gyro_And_AccelCheckBox = uicheckbox(app.Panel_2);
            app.Gyro_And_AccelCheckBox.Enable = 'off';
            app.Gyro_And_AccelCheckBox.Text = 'Gyro_And_Accel';
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '结果绘制';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZeroDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end