classdef AttitudeAlgorithm6KF_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_DataOpen                  matlab.ui.container.Panel
        openIMUData                     matlab.ui.control.Button
        pathIMUData                     matlab.ui.control.EditField
        Panel_ParaSet                   matlab.ui.container.Panel
        dhLabel                         matlab.ui.control.Label
        EditField_GyroNoise             matlab.ui.control.NumericEditField
        dhLabel_2                       matlab.ui.control.Label
        EditField_GyroDriftNoise        matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        EditField_LineAccDecayFactor    matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField_MagDisturDecayFactor  matlab.ui.control.NumericEditField
        mgLabel                         matlab.ui.control.Label
        EditField_AccNoise              matlab.ui.control.NumericEditField
        uTLabel_2                       matlab.ui.control.Label
        EditField_MagNoise              matlab.ui.control.NumericEditField
        ms2Label                        matlab.ui.control.Label
        EditField_LineAccNoise          matlab.ui.control.NumericEditField
        uTLabel                         matlab.ui.control.Label
        EditField_MagDisturNoise        matlab.ui.control.NumericEditField
        Button_SimulationData           matlab.ui.control.Button
        sLabel_2                        matlab.ui.control.Label
        EditField_SimulationTime        matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Panel_InitialSetting            matlab.ui.container.Panel
        CheckBox_InitialYaw             matlab.ui.control.CheckBox
        EditField_InitialYaw            matlab.ui.control.NumericEditField
        CheckBox_InitialPitchRoll       matlab.ui.control.CheckBox
        EditField_InitialRoll           matlab.ui.control.NumericEditField
        CheckBox_InitialRMS_Attitude    matlab.ui.control.CheckBox
        CheckBox_InitialRMS_GyroDrift   matlab.ui.control.CheckBox
        CheckBox_InitialRMS_LineAcc     matlab.ui.control.CheckBox
        CheckBox_InitialRMS_MagDistur   matlab.ui.control.CheckBox
        YLabel                          matlab.ui.control.Label
        EditField_InitialPitch          matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        EditField_InitialRMS_Roll       matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        EditField_InitialRMS_Pitch      matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        EditField_InitialRMS_Yaw        matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        EditField_InitialRMS_GyroDrift_X  matlab.ui.control.NumericEditField
        YLabel_3                        matlab.ui.control.Label
        EditField_InitialRMS_GyroDrift_Y  matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        EditField_InitialRMS_GyroDrift_Z  matlab.ui.control.NumericEditField
        XLabel_3                        matlab.ui.control.Label
        EditField_InitialRMS_LineAcc_X  matlab.ui.control.NumericEditField
        YLabel_4                        matlab.ui.control.Label
        EditField_InitialRMS_LineAcc_Y  matlab.ui.control.NumericEditField
        ZLabel_3                        matlab.ui.control.Label
        EditField_InitialRMS_LineAcc_Z  matlab.ui.control.NumericEditField
        XLabel_4                        matlab.ui.control.Label
        EditField_InitialRMS_MagDistur_X  matlab.ui.control.NumericEditField
        YLabel_5                        matlab.ui.control.Label
        EditField_InitialRMS_MagDistur_Y  matlab.ui.control.NumericEditField
        ZLabel_4                        matlab.ui.control.Label
        EditField_InitialRMS_MagDistur_Z  matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        EditField_Initial_Fs            matlab.ui.control.NumericEditField
        KFLabel                         matlab.ui.control.Label
        EditField_Initial_KFTime        matlab.ui.control.NumericEditField
        Button_Initial_Attitude         matlab.ui.control.Button
        sLabel                          matlab.ui.control.Label
        EditField_Initial_Time          matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField_InitailAtt           matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField_InitailAtt           matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField_InitailAtt           matlab.ui.control.NumericEditField
        RMS_XLabel                      matlab.ui.control.Label
        XEditField_InitailAttRMS        matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        YEditField_InitailAttRMS        matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        ZEditField_InitailAttRMS        matlab.ui.control.NumericEditField
        h_XLabel                        matlab.ui.control.Label
        XEditField_InitailGyroBias      matlab.ui.control.NumericEditField
        YEditFieldLabel_3               matlab.ui.control.Label
        YEditField_InitailGyroBias      matlab.ui.control.NumericEditField
        ZEditFieldLabel_3               matlab.ui.control.Label
        ZEditField_InitailGyroBias      matlab.ui.control.NumericEditField
        RMSh_XLabel                     matlab.ui.control.Label
        XEditField_InitailBiasRMS       matlab.ui.control.NumericEditField
        YEditFieldLabel_4               matlab.ui.control.Label
        YEditField_InitailBiasRMS       matlab.ui.control.NumericEditField
        ZEditFieldLabel_4               matlab.ui.control.Label
        ZEditField_InitailBiasRMS       matlab.ui.control.NumericEditField
        ButtonGroup_DeviceChoose        matlab.ui.container.ButtonGroup
        Button_MPU_L                    matlab.ui.control.RadioButton
        Button_MPU_R                    matlab.ui.control.RadioButton
        Button_ADI_L                    matlab.ui.control.RadioButton
        Button_ADI_R                    matlab.ui.control.RadioButton
        ButtonGroup_AlgorithmChoose     matlab.ui.container.ButtonGroup
        KF9                             matlab.ui.control.RadioButton
        KF12                            matlab.ui.control.RadioButton
        Complement                      matlab.ui.control.RadioButton
        KF6                             matlab.ui.control.RadioButton
        Panel_Draw                      matlab.ui.container.Panel
        CheckBox_DrawNow                matlab.ui.control.CheckBox
        CheckBox_DrawOld1               matlab.ui.control.CheckBox
        CheckBox_DrawOld2               matlab.ui.control.CheckBox
        CheckBox_DrawData_Roll          matlab.ui.control.CheckBox
        CheckBox_DrawData_Pitch         matlab.ui.control.CheckBox
        CheckBox_DrawData_Yaw           matlab.ui.control.CheckBox
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Button_Draw                     matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        CheckBox_DrawData_Xk            matlab.ui.control.CheckBox
        CheckBox_DrawData_Bias          matlab.ui.control.CheckBox
        CheckBox_DrawData_LineAcc       matlab.ui.control.CheckBox
        Label_8                         matlab.ui.control.Label
        Button_DrawOriginal             matlab.ui.control.Button
        GyroCheckBox                    matlab.ui.control.CheckBox
        AccelCheckBox                   matlab.ui.control.CheckBox
        Panel_SaveData                  matlab.ui.container.Panel
        Button_Begin                    matlab.ui.control.Button
        Button_SaveOld1                 matlab.ui.control.Button
        Button_ClearOld1                matlab.ui.control.Button
        CheckBox_DataStateNow           matlab.ui.control.CheckBox
        CheckBox_DataStateOld1          matlab.ui.control.CheckBox
        Button_SaveOld2                 matlab.ui.control.Button
        Button_ClearOld2                matlab.ui.control.Button
        CheckBox_DataStateOld2          matlab.ui.control.CheckBox
        tStartsEditFieldLabel           matlab.ui.control.Label
        tStartEditField                 matlab.ui.control.NumericEditField
        tEndsEditFieldLabel             matlab.ui.control.Label
        tEndEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        % 文件数据操作相关
        imuDataPath    string
        imuDataName    string        
        IMUData        double
        MagneticData    double
        
        % 器件参数设定 后面可以设定为私有属性
        errRMS_Acc          double  %加计测量噪声rms m/s2
        errRMS_Mag          double  %磁强计测量噪声rms uT
        errRMS_Gyr          double  %陀螺测量噪声rms 弧度/s
        errRMS_GyrDrift     double  %陀螺零偏漂移噪声rms 弧度/s
        errRMS_LineAcc      double  %线加速度误差噪声rms m/s2
        errRMS_MagDistu     double  %磁干扰误差噪声rms uT
        decay_LineAcc       double  % （0,1）
        decay_MagDistu      double  % （0,1） 
        
        % 初始设定参数
        initAtt_Roll            double  %初始姿态 横滚角 弧度
        initAtt_Pitch           double  %初始姿态 俯仰角 弧度
        initAtt_Yaw             double  %初始姿态 航向角 弧度
        initErrRMS_AttRoll      double  %初始姿态误差 均方差 弧度
        initErrRMS_AttPitch     double  %初始姿态误差 均方差 弧度
        initErrRMS_AttYaw       double  %初始姿态误差 均方差 弧度
        initErrRMS_GyroDrif_X   double  %初始陀螺零偏误差 均方差 弧度/s
        initErrRMS_GyroDrif_Y   double  %初始陀螺零偏误差 均方差 弧度/s
        initErrRMS_GyroDrif_Z   double  %初始陀螺零偏误差 均方差 弧度/s
        initErrRMS_LinAcc_X     double  %线加速度误差 均方差 m/s2
        initErrRMS_LinAcc_Y     double  %线加速度误差 均方差 m/s2
        initErrRMS_LinAcc_Z     double  %线加速度误差 均方差 m/s2
        initErrRMS_MagDistu_X   double  %磁干扰误差 均方差 uT
        initErrRMS_MagDistu_Y   double  %磁干扰误差 均方差 uT
        initErrRMS_MagDistu_Z   double  %磁干扰误差 均方差 uT
        
        initAttitude            double  %初始化姿态  Z Y X 
        initAttitude_ErrRMS     double  %初始化姿态误差 均方差  Z Y X
        initGyroBias            double  %初始化零偏  X Y Z
        initGyroBias_ErrRMS     double  %初始化零偏误差 均方差  X Y Z
        
        
        Fs                      double   %采样频率    Hz
        Fs_KF                   double   %KF滤波周期
        g                       double  %当前位置的重力加速度
        
        
        % 结果计算输出保存
        dataNow
        dataNow_isOK
        dataOld1
        dataOld1_isOK
        dataOld2
        dataOld2_isOK
        
    end
     
    methods (Access = private)
        % 器件参数初始化函数
        function setDeviceParameter(app)
            % 计算 g 的数值
                g0 = 9.7803267714;                    %单位：m/s2 
                lon = deg2rad(116.397128); lat = deg2rad(39.916527); h = 30;
                app.g=g0*(1+5.27094e-3*sin(lat)^2+2.32718e-5*sin(lat)^4)-3.086e-6*h; % grs80
                app.g = 9.81;              
            
            value = app.EditField_AccNoise.Value;       %mg
            app.errRMS_Acc = value/1000*app.g;              %加计测量噪声rms m/s2   
            
            value = app.EditField_GyroNoise.Value;      % d/h
            app.errRMS_Gyr = value*pi/180/3600;         %陀螺测量噪声rms 弧度/s   
            
            value = app.EditField_GyroDriftNoise.Value; % d/h
            app.errRMS_GyrDrift = value*pi/180/3600;    %陀螺零偏漂移噪声rms 弧度/s
            
            value = app.EditField_LineAccNoise.Value;   %m/s2
            app.errRMS_LineAcc = value;                 %线加速度误差噪声rms m/s2   
            
%             value = app.EditField_MagDisturNoise.Value; %uT
%             app.errRMS_MagDistu = value;                %磁干扰误差噪声rms uT

            value = app.EditField_LineAccDecayFactor.Value;     %(0,1)
            app.decay_LineAcc = value;                          %(0,1)
            
            value = app.EditField_MagDisturDecayFactor.Value;     %(0,1)
            app.decay_MagDistu = value;                          %(0,1)            
        end
        
        %初始 系统参数
        function setInitialParameter(app)
            % 采样频率 和 滤波周期
                value = app.EditField_Initial_Fs.Value;
                app.Fs = value;
                value = app.EditField_Initial_KFTime.Value;
                app.Fs_KF = value;    
                
            % 初始 水平姿态设定
                tmp = app.CheckBox_InitialPitchRoll.Value;
                if tmp == 1
                    % 直接设定初始 水平姿态
                    value = app.EditField_InitialRoll.Value;    %度
                    app.initAtt_Roll = value*pi/180.0;          %弧度
                    value = app.EditField_InitialPitch.Value;    %度
                    app.initAtt_Pitch = value*pi/180.0;          %弧度    
                end
                
            % 初始 航向姿态设定
                tmp = app.CheckBox_InitialYaw.Value;
                if tmp == 1
                    % 直接设定初始 航向角
                    value = app.EditField_InitialYaw.Value;    %度
                    app.initAtt_Yaw = value*pi/180.0;          %弧度      
                end
                
            % 初始方差 参数设定                    
                app.initErrRMS_AttRoll = deg2rad(app.EditField_InitialRMS_Roll.Value);
                app.initErrRMS_AttPitch = deg2rad(app.EditField_InitialRMS_Pitch.Value);
                app.initErrRMS_AttYaw = deg2rad(app.EditField_InitialRMS_Yaw.Value);
                
                app.initErrRMS_GyroDrif_X = deg2rad(app.EditField_InitialRMS_GyroDrift_X.Value);
                app.initErrRMS_GyroDrif_Y = deg2rad(app.EditField_InitialRMS_GyroDrift_Y.Value);
                app.initErrRMS_GyroDrif_Z = deg2rad(app.EditField_InitialRMS_GyroDrift_Z.Value);
                
                app.initErrRMS_LinAcc_X = app.EditField_InitialRMS_LineAcc_X.Value;
                app.initErrRMS_LinAcc_Y = app.EditField_InitialRMS_LineAcc_Y.Value;
                app.initErrRMS_LinAcc_Z = app.EditField_InitialRMS_LineAcc_Z.Value;
        end
        
        % 检查IMU数据是否存在
        function results = checkIMUData(app)
            selectedButton = app.ButtonGroup_AlgorithmChoose.SelectedObject;
            switch selectedButton.Text
                case 'GyroAcc6KF'
                    if isempty(app.IMUData) == 1
                        results = 0;
                        disp('>---警告：IMU数据为空，请重新加载！');
                    else
                        results = 1;
                    end
                case 'GyroAcc9KF'
                    if isempty(app.IMUData) == 1
                        results = 0;
                        disp('>---警告：IMU数据为空，请重新加载！');
                    else
                        results = 1;
                    end
                case 'GyroAccMag12KF'    
                    if (isempty(app.IMUData) == 1) || (isempty(app.MagneticData) == 1)
                        results = 0;
                        disp('>---警告：IMU or Mag 数据为空，请重新加载！');
                    else
                        results = 1;
                    end                    
                otherwise
                    results = 0;
                    disp('>---警告：滤波算法选择错误！');
            end            
        end
    end
    
    methods (Access = public)
        
        % 姿态滤波算法 9维
        function results = attAlgorithm_GyroAcc9KF(app)
            % 1. 获取原始数据
                L = length(app.IMUData);
                tStart = app.tStartEditField.Value;
                tEnd = app.tEndEditField.Value;
                if tEnd == 0
                    tEnd = L;
                else
                    tEnd = tEnd * app.Fs;
                end
                if tStart == 0
                    tStart = 1;
                else
                    tStart = tStart * app.Fs;                    
                end                
                if tEnd <= tStart
                    disp('>---警告：解算时间设置错误！');
                    return;
                else
                    L = tEnd - tStart + 1;
                end
                
                gyroData = zeros(L,3);
                accData = zeros(L,3);
                %   坐标系  NED 和 前右下   加速度计的单位为g
                accData(1:L,1) = app.IMUData(tStart:tEnd,3);  
                accData(1:L,2) = app.IMUData(tStart:tEnd,2);  
                accData(1:L,3) = -app.IMUData(tStart:tEnd,4); 
                accData = accData.*(app.g);
                gyroData(1:L,1) = app.IMUData(tStart:tEnd,6);  
                gyroData(1:L,2) = app.IMUData(tStart:tEnd,5);  
                gyroData(1:L,3) = -app.IMUData(tStart:tEnd,7);                 
            
            % 2. 初始参数设定
                priorOrient = quaternion();     %姿态的先验估计四元数
                postOrient = quaternion();      %姿态的后验估计四元数                
                priorLineAcc = zeros(3,1);      %线加速度的先验估计
                postLineAcc = zeros(3,1);       %线加速度的后验估计
                linAccDecayFactor = app.decay_LineAcc;
                periodSensor = 1.0/app.Fs;        %姿态更新周期
                periodKF = periodSensor * app.Fs_KF;    %KF滤波更新的周期
                gravityVector = [0;0;1];        %NED坐标系下 重力的是矢量方向 单位g
                
                % 陀螺零偏赋初值
                gyroBias = zeros(3,1);          %陀螺的零偏
                gyroBias = mean(gyroData(1:200*3,:))';    %取3秒的数据平均
                
                % 记录数据空间分配
                recordOrient = zeros(L,3);            
                recordGyroBias = zeros(L,3);
                recordLineAcc = zeros(L,3);    
                if app.Fs_KF > 0
                    L_KF = fix(L/app.Fs_KF);
                    recordXk = zeros(L_KF,10);
                    recordPk = zeros(L_KF,9);
                end
                % 惯导解算的中间存储变量
                deltaAng_prior = zeros(3,1);
            % 3. KF滤波相关参数设定
                priorX = zeros(9,1);    postX = zeros(9,1);                 %系统状态量
                priorP = zeros(9,9);    postP = zeros(9,9);                 %系统状态估计方差阵
                Qw = zeros(9,9);        Qv = zeros(3,3);                    %系统噪声方差阵  观测噪声方差阵
                A =zeros(9,9);          H = zeros(3,9);                   %状态矩阵  观测矩阵
                
            % 4. KF滤波参数初始化
                % 初始状态方差阵
                    priorP = diag([app.initErrRMS_AttRoll^2,app.initErrRMS_AttPitch^2,app.initErrRMS_AttYaw^2,...
                        app.initErrRMS_GyroDrif_X^2,app.initErrRMS_GyroDrif_Y^2,app.initErrRMS_GyroDrif_Z^2,...
                        app.initErrRMS_LinAcc_X^2,app.initErrRMS_LinAcc_Y^2,app.initErrRMS_LinAcc_Z^2]);
                % 初始系统噪声方差阵
                    Qw = priorP;
                % 观测噪声方差阵
                    % 陀螺测量噪声
                    Qvg = diag([1,1,1].*(app.errRMS_Gyr^2));
                    % 陀螺零偏漂移 噪声
                    Qwb = diag([1,1,1].*(app.errRMS_GyrDrift^2));
                    % 加速度计测量噪声
                    Qva = diag([1,1,1].*(app.errRMS_Acc^2));
                    % 线加速度估计误差 噪声
                    Qwa = diag([1,1,1].*(app.errRMS_LineAcc));
                    % 系统观测 噪声
                    Qv = (Qvg + Qwb).*(periodKF^2) + Qva + Qwa;
                    
            % 5. 获取初始姿态信息                    
                % 水平姿态角 和 航向角 自对准
                tmp = app.CheckBox_InitialPitchRoll.Value;
                if tmp ~= 1
                    magnetic = [1,0,0]';
                    accLine = [0,0,0]';                                 %初始 线性加速度默认为0，即静止状态
                    %取前3秒的加速度计数据进行计算
                    tmAcc = mean(accData(1:app.Fs*3,:));
                    accGravity =accLine - tmAcc';                %加速度计测量的重力矢量    
                    q = ecompass(accGravity',magnetic');                              
                    % Force rotation angle to be positive
                    if parts(q) < 0
                        q = -q;
                    end                
                    e = euler(q, 'ZYX', 'frame');                     %姿态角  e[航向 俯仰 横滚] 对应 ZYX
                    app.initAtt_Yaw = e(1);
                    app.initAtt_Pitch = e(2);
                    app.initAtt_Roll = e(3);                   
                else
                    % 水平姿态角外部输入
                    value = app.EditField_InitialRoll.Value;    %度
                    app.initAtt_Roll = value*pi/180.0;          %弧度
                    value = app.EditField_InitialPitch.Value;    %度
                    app.initAtt_Pitch = value*pi/180.0;          %弧度    
                end 
 
                % 航向角外部输入的话
                tmp = app.CheckBox_InitialYaw.Value;
                if tmp == 1
                    % 直接设定初始 航向角
                    value = app.EditField_InitialYaw.Value;    %度
                    app.initAtt_Yaw = value*pi/180.0;          %弧度      
                end              
                
                % 初始姿态显示
                app.EditField_InitialYaw.Value = rad2deg(app.initAtt_Yaw);
                app.EditField_InitialPitch.Value = rad2deg(app.initAtt_Pitch);
                app.EditField_InitialRoll.Value = rad2deg(app.initAtt_Roll);              
                
                e = [app.initAtt_Yaw,app.initAtt_Pitch,app.initAtt_Roll];
                priorOrient = quaternion(e,'euler','ZYX','frame');        %初始时刻的姿态                                                               
                
            % 6. 循环解算
                j = 0;
                sumAccData = zeros(1,3);
                j_KF = 1;
                % 由于前面已获得初始姿态，这里就不进行初始姿态的求取了
                for i = 1:L
                % 1. 更新先验估计姿态 陀螺数据时间累积 变成角增量            
                    deltaAng = (gyroData(i,:) - gyroBias').*periodSensor;       %去除先验估计零偏的 角增量；
                    deltaVector = deltaAng + cross(deltaAng_prior,deltaAng)./12;
                    deltaAng_prior = deltaAng;
                    deltaq = quaternion(deltaVector, 'rotvec');
                    
                    priorOrient = priorOrient*deltaq;                                    %利用前一时刻的姿态 更新当前姿态的 先验估计；
                    rotMatriPrior = rotmat(priorOrient, 'frame');                   %利用四元数求取旋转矩阵
                    
                    % 判断是否是静止状态，如果是静止状态则累积加速度计的信息
                    %   静止状态的标志是，在连续 Fs_KF个采样周期内都是静止的 即线加速度的幅值不超过 102%g
                    ampAcc = sqrt(accData(i,1)^2+accData(i,2)^2+accData(i,3)^2);
                    if (abs(ampAcc-app.g)/app.g) <= 0.02
                        j = j + 1;
                        sumAccData = sumAccData + accData(i,:);
                    else
                        j = 0;
                        sumAccData = zeros(1,3);
                    end
                                        
                    
                % 2. 判断是否进入KF环节
                    if app.Fs_KF > 0
                        if j == app.Fs_KF
                         %（1）计算 观测量 Z                                    
                            gravityGyroMeas = rotMatriPrior * gravityVector;    %计算 由陀螺观测到的重力矢量    
                            gravityGyroMeas = gravityGyroMeas.* app.g;
                            priorLineAcc = linAccDecayFactor * postLineAcc;   %计算线性加速度 的先验估计       
                            gravityAccelMeas = priorLineAcc - (sumAccData')./j;      %计算加速度计估计的重力矢量  a= f+g
                            gravityAccelGyroDiff = gravityAccelMeas - gravityGyroMeas;      %观测量的求取
                            
                        % （2）计算 观测矩阵 H   
                            h1 = zeros(3,3);
                            h1(1,2) = gravityGyroMeas(3);
                            h1(1,3) = -gravityGyroMeas(2);
                            h1(2,3) = gravityGyroMeas(1);
                            h1 = h1 - h1.';
                            h2 = -h1.*periodKF; 
                            H = [h1, h2, eye(3)];
                            
                        %（3）计算增益矩阵K
                            priorP = Qw;    %一步预测方差阵
                            tmp =  ((H * priorP * (H.') + Qv).');
                            K = priorP * (H.')  / ( tmp);
                            
                         %（4）实现量测信息更新 得到系统状态的后验估计
                            Z = gravityAccelGyroDiff;
                            XPost = K * Z;
                            
                         %（5）计算X的后验估计协方差阵
                            postP = priorP - K * (H * priorP);
                         
                         %（6）根据postP更新 Qw  这里使用了经验(一些非主对角元素设为0)
                            Qw = [diag(diag(postP(1:3,1:3)))+(diag(diag(postP(4:6,4:6)))+Qwb+Qvg).*(periodKF^2)     (diag(diag(postP(4:6,4:6)))+Qwb).*(-periodKF)	 zeros(3,3);
                                       (diag(diag(postP(4:6,4:6)))+Qwb).*(-periodKF)                                                        diag(diag(postP(4:6,4:6)))+Qwb                                 zeros(3,3);
                                      zeros(3,3)                                                                                        zeros(3,3)                                     diag(diag(postP(7:9,7:9))).*(linAccDecayFactor^2)+Qwa];                        
                         
                          %（7）依据估计结果进行 反馈校正   
                            % 姿态反馈 校正
                                orientErr = XPost(1:3).';
                                qerr = conj(quaternion(orientErr, 'rotvec')); 
                                postOrient = priorOrient * qerr;
                                % Force rotation angle to be positive
                                if parts(postOrient) < 0
                                    postOrient = -postOrient;
                                end 
                                postOrient = normalize(postOrient);
                                priorOrient = postOrient;
                            % 陀螺零偏估计 反馈校正
                                gyroOffsetErr = XPost(4:6);
                                gyroBias = gyroBias - gyroOffsetErr;
                            % 线性加速度估计 反馈校正
                                linAccelErr = XPost(7:9);  
                                postLineAcc = priorLineAcc - linAccelErr;
                
                          %（8）记录中间计算过程变量信息
                                recordXk(j_KF,1:9) = XPost';
                                recordPk(j_KF,:) = (sqrt(diag(postP)))';
                                recordXk(j_KF,10) = i;
                                j_KF = j_KF + 1;
                                
                            j = 0;
                            sumAccData = zeros(1,3);    
                        end       
                    end
                 
                    %记录当前的姿态信息 及 其它信息
                    e = euler(priorOrient, 'ZYX', 'frame');                               
                    recordOrient(i,:) = e';                     
                    recordGyroBias(i,:) = gyroBias';   
                    recordLineAcc(i,:) = postLineAcc';
                end     
            
            % 7. 绘制图形
                figure;
                time = (0:L-1)/app.Fs;
                plot(time,recordOrient.*(180/pi));
                title('Orientation Estimate')
                legend('Z-axis', 'Y-axis', 'X-axis')
                xlabel('Time (s)')
                ylabel('Rotation (degrees)')   
                
            results = 1;
            app.CheckBox_DataStateNow.Value = 1;
            app.dataNow.recordOrient = recordOrient;
            app.dataNow.recordGyroBias = recordGyroBias;
            app.dataNow.recordLineAcc = recordLineAcc;
            app.dataNow.Fs = app.Fs;
            app.dataNow.Fs_KF = app.Fs_KF;
            app.dataNow_isOK = 1;
            if app.Fs_KF > 0
                app.dataNow.recordXk = recordXk(1:j_KF-1,:);
                app.dataNow.recordPk = recordPk(1:j_KF-1,:);
            end

        end
        
        % 姿态滤波算法 6为KF 
        function results = attAlgorithm_GyroAcc6KF(app)
            % 1. 获取原始数据
                L = length(app.IMUData);
                tStart = app.tStartEditField.Value;
                tEnd = app.tEndEditField.Value;
                if tEnd == 0
                    tEnd = L;
                else
                    tEnd = tEnd * app.Fs;
                    if tEnd >= L
                        tEnd = L;
                    end
                end
                if tStart == 0
                    tStart = 1;
                else
                    tStart = tStart * app.Fs; 
                    if tStart >= L
                        tStart = 1;
                    end
                end                
                if tEnd <= tStart
                    disp('>---警告：解算时间设置错误！');
                    return;
                else
                    L = tEnd - tStart + 1;
                end
                % 获取原始数据，这里注意 要将 ENU 坐标系 转到 NED
                gyroData = zeros(L,3);
                accData = zeros(L,3);
                %   坐标系  NED 和 前右下   加速度计的单位为g
                accData(1:L,1) = app.IMUData(tStart:tEnd,3);  
                accData(1:L,2) = app.IMUData(tStart:tEnd,2);  
                accData(1:L,3) = -app.IMUData(tStart:tEnd,4); 

                gyroData(1:L,1) = app.IMUData(tStart:tEnd,6);  
                gyroData(1:L,2) = app.IMUData(tStart:tEnd,5);  
                gyroData(1:L,3) = -app.IMUData(tStart:tEnd,7);             
            

            % 2. 初始参数设定
                priorOrient = quaternion();     %姿态的先验估计四元数
                postOrient = quaternion();      %姿态的后验估计四元数                

                periodSensor = 1.0/app.Fs;        %姿态更新周期
                periodKF = periodSensor * app.Fs_KF;    %KF滤波更新的周期
                
                gravityVector = [0;0;1];        %NED坐标系下 重力的是矢量方向 单位g
                
                % 陀螺零偏赋初值
                if isempty(app.initGyroBias)
                    msgbox('未初始化姿态！');
                    return;
                end
                gyroBias = app.initGyroBias';          %陀螺的零偏
                
                % 记录数据空间分配
                recordOrient = zeros(L,3);            
                recordGyroBias = zeros(L,3);  
                if app.Fs_KF > 0
                    L_KF = fix(L/app.Fs_KF);
                    recordXk = zeros(L_KF,6);
                    recordPk = zeros(L_KF,6);
                    recordKFTime = zeros(L_KF,1);
                    
                    recordTmpGra = zeros(L_KF,3);
                end
                % 惯导解算的中间存储变量 用于进行旋转矢量补偿 前一时刻的角增量
                deltaAng_prior = zeros(3,1);
                
            % 3. KF滤波相关参数设定  6维  仅观测 姿态误差 陀螺零偏
                priorX = zeros(6,1);    postX = zeros(6,1);       %系统状态量                
                
            % 4. KF滤波参数初始化
                % 初始方差阵P0 6维 姿态误差  零偏误差
                    initVar_Roll = app.initAttitude_ErrRMS(1,3)^2;
                    initVar_Pitch = app.initAttitude_ErrRMS(1,2)^2;
                    initVar_Yaw = app.initAttitude_ErrRMS(1,1)^2;
                    initVar_BiasX = app.initGyroBias_ErrRMS(1,1)^2;
                    initVar_BiasY = app.initGyroBias_ErrRMS(1,2)^2;
                    initVar_BiasZ = app.initGyroBias_ErrRMS(1,3)^2;
                    postP = diag([initVar_Roll,initVar_Pitch,initVar_Yaw,initVar_BiasX,initVar_BiasY,initVar_BiasZ]);
                                        
                % 系统状态噪声方差阵
                    varGyroNoise = (app.EditField_GyroNoise.Value*(pi/180/3600))^2;
                    varGyroDriftNoise = (app.EditField_GyroDriftNoise.Value*(pi/180/3600))^2;
                    Qw = diag([[1,1,1].*((periodKF^2)*(varGyroNoise+varGyroDriftNoise)),[1,1,1].*varGyroDriftNoise]);
                
                % 系统测量噪声方差阵
                    varAccelNoise = (app.EditField_AccNoise.Value/1000)^2;      %单位 g
                    Qv = diag([1,1,1].*varAccelNoise);
                     Qv = Qv./100;
                % 状态转移矩阵  观测矩阵
                    A = [eye(3),-eye(3).*periodKF;zeros(3,3),eye(3)];
                    H = zeros(3,6);
                    
                    
            % 5. 获取初始姿态信息                    
                % 水平姿态角 和 航向角(默认为0) 自对准  
                priorOrient = quaternion(app.initAttitude,'euler','ZYX','frame');        %初始时刻的姿态                                                               
                
                tmpAccel = mean(accData(1:3*200,:));
                
            % 6. 循环解算
                j = 0;
                sumAccData = zeros(1,3);
                piorGravity = zeros(3,1);
                a = 0.99;
                j_KF = 1;
                % 由于前面已获得初始姿态，这里就不进行初始姿态的求取了
                for i = 1:L
                % 1. 更新先验估计姿态 陀螺数据时间累积 变成角增量            
                    deltaAng = (gyroData(i,:) - gyroBias').*periodSensor;       %去除先验估计零偏的 角增量；
                    deltaVector = deltaAng + cross(deltaAng_prior,deltaAng)./12;
                    deltaAng_prior = deltaAng;
                    deltaq = quaternion(deltaVector, 'rotvec');
                    
                    priorOrient = priorOrient*deltaq;                                    %利用前一时刻的姿态 更新当前姿态的 先验估计；
                    priorOrient = normalize(priorOrient);
                    rotMatriPrior = rotmat(priorOrient, 'frame');                   %利用四元数求取旋转矩阵
                    
                    % 判断是否是静止状态，如果是静止状态则累积加速度计的信息
                    %   静止状态的标志是，在连续 Fs_KF个采样周期内都是静止的 即线加速度的幅值不超过 102%g
                    ampAcc = sqrt(accData(i,1)^2+accData(i,2)^2+accData(i,3)^2);
                    if (abs(ampAcc-1)/1) <= 0.02
                        j = j + 1;
                        sumAccData = sumAccData + accData(i,:);
                        if j > app.Fs_KF
                            j = 0;
                            sumAccData = zeros(1,3);
                        end
                    else
                        j = 0;
                        sumAccData = zeros(1,3);
                    end
                                        
                    
                % 2. 判断是否进入KF环节
                    if app.Fs_KF > 0
%                         if (j == app.Fs_KF)&&(mod(i,200)==0)
                        if j == app.Fs_KF
                         %（1）计算 观测量 Z                                    
                            gravityGyroMeas = rotMatriPrior * gravityVector;    %计算 由陀螺观测到的重力矢量                                   
                            gravityAccelMeas = -(sumAccData')./j;               %计算加速度计估计的重力矢量  g= a-f
%                             gravityAccelMeas = piorGravity.*a + gravityAccelMeas.*(1-a);
%                             piorGravity = gravityAccelMeas;
                             gravityAccelMeas = -tmpAccel';
                            gravityAccelGyroDiff = gravityAccelMeas - gravityGyroMeas;      %观测量的求取
                            
                        % （2）计算 观测矩阵 H   
                            h1 = zeros(3,3);
                            h1(1,2) = gravityGyroMeas(3);
                            h1(1,3) = -gravityGyroMeas(2);
                            h1(2,3) = gravityGyroMeas(1);
                            h1 = h1 - h1.';
                            h2 = -h1.*periodKF; 
                            H = [h1, h2];
                            
                        %（3）计算 一步预测方差阵 priorP
                            priorP = A * postP * A' + Qw;
                            
                        %（4）计算增益矩阵K                            
                            tmp =  ((H * priorP * (H.') + Qv).');
                            K = priorP * (H.')  / (tmp);
                            
                        %（5）实现量测信息更新 得到系统状态的后验估计
                            Z = gravityAccelGyroDiff;
                            XPost = K * Z;
                            
                         %（6）计算X的后验估计协方差阵
                            postP = priorP - K * (H * priorP);  
                         
                          %（7）依据估计结果进行 反馈校正   
                            % 姿态反馈 校正
                                orientErr = XPost(1:3).';
                                orientErr(1,3) = 0;
                                qerr = conj(quaternion(orientErr, 'rotvec')); 
                                postOrient = priorOrient * qerr;
                                % Force rotation angle to be positive
                                if parts(postOrient) < 0
                                    postOrient = -postOrient;
                                end 
                                postOrient = normalize(postOrient);
                                priorOrient = postOrient;
                            % 陀螺零偏估计 反馈校正
                                gyroOffsetErr = XPost(4:6);
                                gyroOffsetErr(3,1) = 0;
                                gyroBias = gyroBias - gyroOffsetErr;
                
                          %（8）记录中间计算过程变量信息
                                recordXk(j_KF,1:6) = XPost';
                                recordPk(j_KF,:) = (sqrt(diag(postP)))';
                                recordKFTime(j_KF,1) = i;
                                j_KF = j_KF + 1;
                                
                            % 测试
                            rotMatriPrior = rotmat(priorOrient, 'frame');   
                            gravityGyroMeas = rotMatriPrior * gravityVector;                            
                            recordTmpGra(j_KF,:) = gravityAccelMeas - gravityGyroMeas;
                                
                                
                            j = 0;
                            sumAccData = zeros(1,3);    
                        end       
                    end
                 
                    %记录当前的姿态信息 及 其它信息
                    e = euler(priorOrient, 'ZYX', 'frame');                               
                    recordOrient(i,:) = e';                     
                    recordGyroBias(i,:) = gyroBias';                       

                end     
            
            % 7. 绘制图形
                figure;
                time = (0:L-1)/app.Fs;
                plot(time,recordOrient.*(180/pi));
                title('Orientation Estimate')
                legend('Z-axis', 'Y-axis', 'X-axis')
                xlabel('Time (s)')
                ylabel('Rotation (degrees)')   
                

            app.CheckBox_DataStateNow.Value = 1;
            app.dataNow.recordOrient = recordOrient;
            app.dataNow.recordGyroBias = recordGyroBias;
            app.dataNow.Fs = app.Fs;
            app.dataNow.Fs_KF = app.Fs_KF;
            app.dataNow_isOK = 1;
            if app.Fs_KF > 0
                app.dataNow.recordXk = recordXk(1:j_KF-1,:);
                app.dataNow.recordPk = recordPk(1:j_KF-1,:);
                app.dataNow.recordKFTime = recordKFTime(1:j_KF-1,1);
            end      
            
            results = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dataNow_isOK = 0;
            app.dataOld1_isOK = 0;
            app.dataOld2_isOK = 0;
            
            app.KF6.Value = 1; % 算法选择
            
            app.Button_ADI_R.Value = 1;            
                app.EditField_AccNoise.Value = 2.3;     %mg
                app.EditField_MagNoise.Value = 0.0;     %uT
                app.EditField_GyroNoise.Value = 104.85;    %度/h
                app.EditField_GyroDriftNoise.Value = 3.29;  %度/h
                app.EditField_LineAccNoise.Value = 0.01;     %m/s2
                app.EditField_MagDisturNoise.Value = 0.0;   %uT
                app.EditField_LineAccDecayFactor.Value = 0.5;     
                app.EditField_MagDisturDecayFactor.Value = 0.5;          
            
            app.Fs = app.EditField_Initial_Fs.Value;
            app.Fs_KF = app.EditField_Initial_KFTime.Value;
        end

        % Button pushed function: openIMUData
        function openIMUDataButtonPushed(app, event)
            [IMU_FileName,IMU_FilePath] = uigetfile('*.mat');
            if isequal(IMU_FileName,0)
               disp('>-----------警告：未选中IMU数据！！！');
            else               
               app.imuDataPath = IMU_FilePath;
               app.imuDataName = IMU_FileName;
               app.pathIMUData.Value = fullfile(IMU_FilePath,IMU_FileName);
               load(fullfile(IMU_FilePath,IMU_FileName));
               if exist('IMU') ~= 1
                   disp('>-----------警告：IMU数据为空！');
               else
                   app.IMUData = IMU;
               end
               if exist('Magnetic') ==1
                   app.MagneticData = Magnetic;
               end
            end            
        end

        % Selection changed function: ButtonGroup_DeviceChoose
        function ButtonGroup_DeviceChooseSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_DeviceChoose.SelectedObject;
            switch selectedButton.Text
                case 'MPU_L'
                    disp('>-----选中设备MPU_L，设置对应参数！');
                    app.EditField_AccNoise.Value = 8.0;     %mg
                    app.EditField_MagNoise.Value = 0.0;     %uT
                    app.EditField_GyroNoise.Value = 360;    %度/h
                    app.EditField_GyroDriftNoise.Value = 0.1138;  %度/h
                    app.EditField_LineAccNoise.Value = 0.01;     %m/s2
                    app.EditField_MagDisturNoise.Value = 0.0;   %uT
                    app.EditField_LineAccDecayFactor.Value = 0.5;     
                    app.EditField_MagDisturDecayFactor.Value = 0.5;
                case 'MPU_R'
                    disp('选中设备MPU_R');
                    app.EditField_AccNoise.Value = 2.3;     %mg
                    app.EditField_MagNoise.Value = 0.0;     %uT
                    app.EditField_GyroNoise.Value = 104.85;    %度/h
                    app.EditField_GyroDriftNoise.Value = 3.29;  %度/h
                    app.EditField_LineAccNoise.Value = 0.01;     %m/s2
                    app.EditField_MagDisturNoise.Value = 0.0;   %uT
                    app.EditField_LineAccDecayFactor.Value = 0.5;     
                    app.EditField_MagDisturDecayFactor.Value = 0.5;
                case 'ADI_L'
                    disp('选中设备ADI_L');
                    app.EditField_AccNoise.Value = 0.0;     %mg
                    app.EditField_MagNoise.Value = 0.0;     %uT
                    app.EditField_GyroNoise.Value = 0;    %度/h
                    app.EditField_GyroDriftNoise.Value = 0.0;  %度/h
                    app.EditField_LineAccNoise.Value = 0.0;     %m/s2
                    app.EditField_MagDisturNoise.Value = 0.0;   %uT
                    app.EditField_LineAccDecayFactor.Value = 0.5;     
                    app.EditField_MagDisturDecayFactor.Value = 0.5;
                case 'ADI_R'
                    disp('选中设备ADI_R');        
                    app.EditField_AccNoise.Value = 2.3;     %mg
                    app.EditField_MagNoise.Value = 0.0;     %uT
                    app.EditField_GyroNoise.Value = 104.85;    %度/h
                    app.EditField_GyroDriftNoise.Value = 3.29;  %度/h
                    app.EditField_LineAccNoise.Value = 0.01;     %m/s2
                    app.EditField_MagDisturNoise.Value = 0.0;   %uT
                    app.EditField_LineAccDecayFactor.Value = 0.5;     
                    app.EditField_MagDisturDecayFactor.Value = 0.5; 
                otherwise
                    disp('选择设备出错！');
            end
        end

        % Value changed function: CheckBox_InitialPitchRoll
        function CheckBox_InitialPitchRollValueChanged(app, event)
            value = app.CheckBox_InitialPitchRoll.Value;
            if value == 1
                app.EditField_InitialRoll.Editable = 1;
                app.EditField_InitialPitch.Editable = 1;
            else
                app.EditField_InitialRoll.Editable = 0;
                app.EditField_InitialRoll.Value = 0.0;
                app.EditField_InitialPitch.Editable = 0;
                app.EditField_InitialPitch.Value = 0.0;
            end
        end

        % Value changed function: CheckBox_InitialYaw
        function CheckBox_InitialYawValueChanged(app, event)
            value = app.CheckBox_InitialYaw.Value;
            if value == 1
                app.EditField_InitialYaw.Editable = 1;
            else
                app.EditField_InitialYaw.Editable = 0;
                app.EditField_InitialYaw.Value = 0.0;
            end            
        end

        % Button pushed function: Button_Begin
        function Button_BeginPushed(app, event)
            % 1. 测试数据是否存在
                results = checkIMUData(app);
                if results == 0
                    return;
                end
                 
            % 2. 初始化器件参数
                setDeviceParameter(app);
                
            % 3. 初始化 系统参数
                setInitialParameter(app);                
            
            % 4. 姿态解算
                selectedButton = app.ButtonGroup_AlgorithmChoose.SelectedObject;
                switch selectedButton.Text
                    case 'GyroAcc6KF'
                        results = attAlgorithm_GyroAcc6KF(app);
                    case 'GyroAcc9KF'
                        % 6维 陀螺加计滤波算法
                        results = attAlgorithm_GyroAcc9KF(app);
                    case 'GyroAccMag12KF'    
                        % 12维 陀螺加计磁强计滤波算法        
                        
                    case 'Complement'    
                        % 互补滤波算法     
                        
                    otherwise
                        disp('>---警告：滤波算法选择错误！');
                end            
                

        end

        % Button pushed function: Button_SaveOld1
        function Button_SaveOld1Pushed(app, event)
            app.dataOld1 = app.dataNow;
            app.dataOld1_isOK = 1;
            app.CheckBox_DataStateOld1.Value = 1;
        end

        % Button pushed function: Button_ClearOld1
        function Button_ClearOld1Pushed(app, event)
            app.dataOld1 = [];
            app.dataOld1_isOK = 0;
            app.CheckBox_DataStateOld1.Value = 0;
        end

        % Button pushed function: Button_SaveOld2
        function Button_SaveOld2Pushed(app, event)
            app.dataOld2 = app.dataNow;
            app.dataOld2_isOK = 1;
            app.CheckBox_DataStateOld2.Value = 1;            
        end

        % Button pushed function: Button_ClearOld2
        function Button_ClearOld2Pushed(app, event)
            app.dataOld2 = [];
            app.dataOld2_isOK = 0;
            app.CheckBox_DataStateOld2.Value = 0;            
        end

        % Button pushed function: Button_Draw
        function Button_DrawPushed(app, event)
%                         app.dataNow.recordOrient = recordOrient;
%             app.dataNow.recordGyroBias = recordGyroBias;
%             app.dataNow.recordLineAcc = recordLineAcc;
%             app.dataNow.recordXk = recordXk;
%             app.dataNow.recordPk = recordPk;            
%----------------------绘制横滚角 X-----------------------------
            value = app.CheckBox_DrawData_Roll.Value;
            if value == 1
                tmp_CheckNow = app.CheckBox_DrawNow.Value==1 && app.dataNow_isOK==1;
                tmp_CheckOld1 = app.CheckBox_DrawOld1.Value==1 && app.dataOld1_isOK==1;
                tmp_CheckOld2 = app.CheckBox_DrawOld2.Value==1 && app.dataOld2_isOK==1;
                
                if tmp_CheckNow || tmp_CheckOld1 || tmp_CheckOld2
                    figure; 
                    xlabel('time(s)');
                    ylabel('Roll(deg)');
                    title('姿态--横滚角X');  
                    
                    % 绘制当前的数据                 
                    if tmp_CheckNow                   
                       tmp = rad2deg(app.dataNow.recordOrient(:,3));
                       L = length(tmp);
                       t = (0:L-1)./app.dataNow.Fs;               
                       hold on;
                       plot(t,tmp,'Color','k','DisplayName','Now');     
                    end                
                    
                    % 绘制 old 1的数据                
                    if tmp_CheckOld1                  
                       tmp = rad2deg(app.dataOld1.recordOrient(:,3));
                       L = length(tmp);
                       t = (0:L-1)./app.dataOld1.Fs;
                       hold on;
                       plot(t,tmp,'Color','b','DisplayName','Old1');                      
                    end    
                    
                    % 绘制 old 2的数据
                    if tmp_CheckOld2                   
                       tmp = rad2deg(app.dataOld2.recordOrient(:,3));
                       L = length(tmp);
                       t = (0:L-1)./app.dataOld2.Fs;
                       hold on;
                       plot(t,tmp,'Color','r','DisplayName','Old2');                            
                    end   
                    legend;
                end
            end
            
%---------------------------绘制俯仰角 Y-----------------------
            value = app.CheckBox_DrawData_Pitch.Value;
            if value == 1
                tmp_CheckNow = app.CheckBox_DrawNow.Value==1 && app.dataNow_isOK==1;
                tmp_CheckOld1 = app.CheckBox_DrawOld1.Value==1 && app.dataOld1_isOK==1;
                tmp_CheckOld2 = app.CheckBox_DrawOld2.Value==1 && app.dataOld2_isOK==1;
                
                if tmp_CheckNow || tmp_CheckOld1 || tmp_CheckOld2
                    figure; 
                    xlabel('time(s)');
                    ylabel('Roll(deg)');
                    title('姿态--俯仰角Y');   
                    % 绘制当前的数据                 
                    if tmp_CheckNow                   
                       tmp = rad2deg(app.dataNow.recordOrient(:,2));
                       L = length(tmp);
                       t = (0:L-1)./app.dataNow.Fs;
                       hold on;
                       plot(t,tmp,'Color','k','DisplayName','Now');                   
                    end                
                    
                    % 绘制 old 1的数据                
                    if tmp_CheckOld1                  
                       tmp = rad2deg(app.dataOld1.recordOrient(:,2));
                       L = length(tmp);
                       t = (0:L-1)./app.dataOld1.Fs;
                       hold on;
                       plot(t,tmp,'Color','b','DisplayName','Old1');                      
                    end    
                    
                    % 绘制 old 2的数据
                    if tmp_CheckOld2                   
                       tmp = rad2deg(app.dataOld2.recordOrient(:,2));
                       L = length(tmp);
                       t = (0:L-1)./app.dataOld2.Fs;
                       hold on;
                       plot(t,tmp,'Color','r','DisplayName','Old2');                            
                    end                                   
                    legend;
                end
            end  
            
%-------------------------绘制航向角 Z -------------------------
            value = app.CheckBox_DrawData_Yaw.Value;
            if value == 1
                tmp_CheckNow = app.CheckBox_DrawNow.Value==1 && app.dataNow_isOK==1;
                tmp_CheckOld1 = app.CheckBox_DrawOld1.Value==1 && app.dataOld1_isOK==1;
                tmp_CheckOld2 = app.CheckBox_DrawOld2.Value==1 && app.dataOld2_isOK==1;
                
                if tmp_CheckNow || tmp_CheckOld1 || tmp_CheckOld2
                    figure; 
                    xlabel('time(s)');
                    ylabel('Roll(deg)');
                    title('姿态--航向角Z');   
                    
                    % 绘制当前的数据                 
                    if tmp_CheckNow                   
                       tmp = rad2deg(app.dataNow.recordOrient(:,1));
                       L = length(tmp);
                       t = (0:L-1)./app.dataNow.Fs;
                       hold on;
                       plot(t,tmp,'Color','k','DisplayName','Now');                   
                    end                
                    
                    % 绘制 old 1的数据                
                    if tmp_CheckOld1                  
                       tmp = rad2deg(app.dataOld1.recordOrient(:,1));
                       L = length(tmp);
                       t = (0:L-1)./app.dataOld1.Fs;
                       hold on;
                       plot(t,tmp,'Color','b','DisplayName','Old1');                      
                    end    
                    
                    % 绘制 old 2的数据
                    if tmp_CheckOld2                   
                       tmp = rad2deg(app.dataOld2.recordOrient(:,1));
                       L = length(tmp);
                       t = (0:L-1)./app.dataOld2.Fs;
                       hold on;
                       plot(t,tmp,'Color','r','DisplayName','Old2');                            
                    end     
                    legend;
                end
            end
            
%-------------------------绘制线加速度估计 -------------------------
            value = app.CheckBox_DrawData_LineAcc.Value;
            if value == 1
                tmp_CheckNow = app.CheckBox_DrawNow.Value==1 && app.dataNow_isOK==1;
                tmp_CheckOld1 = app.CheckBox_DrawOld1.Value==1 && app.dataOld1_isOK==1;
                tmp_CheckOld2 = app.CheckBox_DrawOld2.Value==1 && app.dataOld2_isOK==1;
                
                if tmp_CheckNow || tmp_CheckOld1 || tmp_CheckOld2
                    figure;      
                    %--------绘制X轴---------------
                    subplot(1,3,1);                         
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('s系X轴线加速度'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordLineAcc(:,1);
                        L = length(tmp);
                        t = (0:L-1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordLineAcc(:,1);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld1.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordLineAcc(:,1);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld2.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end    
                    legend;
                    %--------绘制Y轴---------------
                    subplot(1,3,2);  
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('s系Y轴线加速度'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordLineAcc(:,2);
                        L = length(tmp);
                        t = (0:L-1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordLineAcc(:,2);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld1.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordLineAcc(:,2);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld2.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end      
                    legend;
                    %--------绘制Z轴---------------
                    subplot(1,3,3);       
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('s系Z轴线加速度'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordLineAcc(:,3);
                        L = length(tmp);
                        t = (0:L-1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordLineAcc(:,3);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld1.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordLineAcc(:,3);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld2.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end  
                    legend;
                end   
            end            
            
%-------------------------绘制 陀螺零偏 估计 -------------------------
            value = app.CheckBox_DrawData_Bias.Value;
            if value == 1
                tmp_CheckNow = app.CheckBox_DrawNow.Value==1 && app.dataNow_isOK==1;
                tmp_CheckOld1 = app.CheckBox_DrawOld1.Value==1 && app.dataOld1_isOK==1;
                tmp_CheckOld2 = app.CheckBox_DrawOld2.Value==1 && app.dataOld2_isOK==1;
                
                if tmp_CheckNow || tmp_CheckOld1 || tmp_CheckOld2
                    figure;       
                    %--------绘制X轴---------------
                    subplot(1,3,1);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('X轴陀螺零偏'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordGyroBias(:,1).*(180/pi*3600);                        
                        L = length(tmp);
                        t = (0:L-1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordGyroBias(:,1).*(180/pi*3600);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld1.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordGyroBias(:,1).*(180/pi*3600);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld2.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end  
                    legend;
                    %--------绘制Y轴---------------
                    subplot(1,3,2);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('Y轴陀螺零偏'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordGyroBias(:,2).*(180/pi*3600);                        
                        L = length(tmp);
                        t = (0:L-1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordGyroBias(:,2).*(180/pi*3600);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld1.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordGyroBias(:,2).*(180/pi*3600);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld2.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end               
                    legend;
                    %--------绘制Z轴---------------
                    subplot(1,3,3);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('Z轴陀螺零偏'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordGyroBias(:,3).*(180/pi*3600);                        
                        L = length(tmp);
                        t = (0:L-1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordGyroBias(:,3).*(180/pi*3600);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld1.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordGyroBias(:,3).*(180/pi*3600);
                        L = length(tmp);
                        t = (0:L-1)./app.dataOld2.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end 
                    legend;
                end
            end   
            
%-----------------绘制 滤波状态量 Xk -----------------------
            value = app.CheckBox_DrawData_Xk.Value;
            if value == 1
                tmp_CheckNow = app.CheckBox_DrawNow.Value==1 && app.dataNow_isOK==1;
                tmp_CheckOld1 = app.CheckBox_DrawOld1.Value==1 && app.dataOld1_isOK==1;
                tmp_CheckOld2 = app.CheckBox_DrawOld2.Value==1 && app.dataOld2_isOK==1;
                
                if tmp_CheckNow || tmp_CheckOld1 || tmp_CheckOld2
          %----------- 绘制 姿态误差--------------    
                    figure
                    %--------绘制 姿态误差 x1---------------
                    subplot(2,3,1);       
                    xlabel('time(s)');
                    ylabel('度');
                    title('姿态误差 X1'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,1).*(180/pi);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;   
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,1).*(180/pi);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,1).*(180/pi);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end
                    legend;
                    %--------绘制 姿态误差 x2---------------
                    subplot(2,3,2);       
                    xlabel('time(s)');
                    ylabel('度');
                    title('姿态误差 X2'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,2).*(180/pi);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,2).*(180/pi);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,2).*(180/pi);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end        
                    legend;
                    %--------绘制 姿态误差 x3---------------
                    subplot(2,3,3);       
                    xlabel('time(s)');
                    ylabel('度');
                    title('姿态误差 X3'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,3).*(180/pi);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,3).*(180/pi);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,3).*(180/pi);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end        
                    legend;
                    %--------绘制 方差 P1---------------
                    subplot(2,3,4);       
                    xlabel('time(s)');
                    ylabel('度');
                    title('姿态误差均方差 P1'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,1).^(0.5); 
                        tmp = tmp.*(180/pi);
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                     
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,1).^(0.5);
                        tmp = tmp.*(180/pi);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,1).^(0.5);
                        tmp = tmp.*(180/pi);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end                 
                    legend;
                    %--------绘制 方差 P2---------------
                    subplot(2,3,5);       
                    xlabel('time(s)');
                    ylabel('度');
                    title('姿态误差均方差 P2'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,2).^(0.5); 
                        tmp = tmp.*(180/pi);
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,2).^(0.5);
                        tmp = tmp.*(180/pi);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,2).^(0.5);
                        tmp = tmp.*(180/pi);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end 
                    legend;
                    %--------绘制 方差 P3---------------
                    subplot(2,3,6);       
                    xlabel('time(s)');
                    ylabel('度');
                    title('姿态误差均方差 P3'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,3).^(0.5); 
                        tmp = tmp.*(180/pi);
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                       
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,3).^(0.5);
                        tmp = tmp.*(180/pi);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,3).^(0.5);
                        tmp = tmp.*(180/pi);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end      
                    legend;
         %----------- 绘制 陀螺零偏估计误差 及 方差--------------    
                    figure
                    %--------绘制 陀螺零偏估计误差 x4---------------
                    subplot(2,3,1);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('陀螺零偏估计误差 X4'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,4).*(180/pi*3600);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                       
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,4).*(180/pi*3600);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,4).*(180/pi*3600);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end
                    legend;
                    %--------绘制 陀螺零偏估计误差 x5---------------
                    subplot(2,3,2);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('陀螺零偏估计误差 X5'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,5).*(180/pi*3600);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                       
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,5).*(180/pi*3600);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,5).*(180/pi*3600);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end      
                    legend;
                    %--------绘制 陀螺零偏估计误差 x6---------------
                    subplot(2,3,3);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('陀螺零偏估计误差 X5'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,6).*(180/pi*3600);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                      
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,6).*(180/pi*3600);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,6).*(180/pi*3600);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end            
                    legend;
                    %--------绘制 方差 P4---------------
                    subplot(2,3,4);       
                    xlabel('time(s)');
                    ylabel('度');
                    title('陀螺零偏误差均方差 P4'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,4).^(0.5); 
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                     
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,4).^(0.5);
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,4).^(0.5);
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end      
                    legend;
                    %--------绘制 方差 P5---------------
                    subplot(2,3,5);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('陀螺零偏误差均方差 P5'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,5).^(0.5); 
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                   
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,5).^(0.5);
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,5).^(0.5);
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end 
                    legend;
                    %--------绘制 方差 P6---------------
                    subplot(2,3,6);       
                    xlabel('time(s)');
                    ylabel('度/h');
                    title('陀螺零偏误差均方差 P6'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,6).^(0.5); 
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                       
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,6).^(0.5);
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,6).^(0.5);
                        tmp = tmp.*(180/pi*3600);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end
                    legend;
         %----------- 绘制 线加速度估计误差 及 方差--------------    
         if app.ButtonGroup_AlgorithmChoose.SelectedObject.Text == 'GyroAcc9KF'            
                        
                        
                    figure
                    %--------绘制 线加速度估计误差 x7---------------
                    subplot(2,3,1);       
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('线加速度估计误差 X7'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,7);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                   
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,7);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,7);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end
                    legend;
                    %--------绘制 线加速度估计误差 x8---------------
                    subplot(2,3,2);       
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('线加速度估计误差 X8'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,8);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                      
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,8);
                        L = length(tmp);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,8);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end     
                    legend;
                    %--------绘制 线加速度估计误差 x9---------------
                    subplot(2,3,3);       
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('线加速度估计误差 X9'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordXk(:,9);                        
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                      
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordXk(:,9);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordXk(:,9);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end        
                    legend;
                    %--------绘制 方差 P7---------------
                    subplot(2,3,4);       
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('线加速度估计误差均方差 P7'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,7).^(0.5); 
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                      
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,7).^(0.5);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,7).^(0.5);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end       
                    legend;
                    %--------绘制 方差 P8---------------
                    subplot(2,3,5);       
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('线加速度估计误差均方差 P8'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,8).^(0.5); 
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                    
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,8).^(0.5);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,8).^(0.5);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end 
                    legend;
                    %--------绘制 方差 P9---------------
                    subplot(2,3,6);       
                    xlabel('time(s)');
                    ylabel('m/s2');
                    title('线加速度估计误差均方差 P9'); 
                    if tmp_CheckNow                        
                        tmp = app.dataNow.recordPk(:,9).^(0.5); 
                        t = app.dataNow.recordKFTime(:,1)./app.dataNow.Fs;                     
                        hold on;
                        plot(t,tmp,'Color','k','DisplayName','Now');
                    end
                    if tmp_CheckOld1                        
                        tmp = app.dataOld1.recordPk(:,9).^(0.5);
                        t = app.dataOld1.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','b','DisplayName','Old1');
                    end                    
                    if tmp_CheckOld2                        
                        tmp = app.dataOld2.recordPk(:,9).^(0.5);
                        t = app.dataOld2.recordKFTime(:,1)./app.dataNow.Fs;                        
                        hold on;
                        plot(t,tmp,'Color','r','DisplayName','Old2');
                    end   
                    legend;
         end
                end
            end              
             
        end

        % Value changed function: EditField_Initial_KFTime
        function EditField_Initial_KFTimeValueChanged(app, event)
            value = app.EditField_Initial_KFTime.Value;
            app.Fs_KF = value;
        end

        % Button pushed function: Button_DrawOriginal
        function Button_DrawOriginalPushed(app, event)
                L = length(app.IMUData);
                tStart = app.tStartEditField.Value;
                tEnd = app.tEndEditField.Value;
                if tEnd == 0
                    tEnd = L;
                else
                    tEnd = tEnd * app.Fs;
                    if tEnd >= L
                        tEnd = L;
                    end
                end
                if tStart == 0
                    tStart = 1;
                else
                    tStart = tStart * app.Fs; 
                    if tStart >= L
                        tStart = 1;
                    end
                end                
                if tEnd <= tStart
                    disp('>---警告：解算时间设置错误！');
                    return;
                else
                    L = tEnd - tStart + 1;
                end
                % 获取原始数据，这里注意 要将 ENU 坐标系 转到 NED
                gyroData = zeros(L,3);
                accData = zeros(L,3);
                %   坐标系  NED 和 前右下   加速度计的单位为g
                accData(1:L,1) = app.IMUData(tStart:tEnd,3);  
                accData(1:L,2) = app.IMUData(tStart:tEnd,2);  
                accData(1:L,3) = -app.IMUData(tStart:tEnd,4); 

                gyroData(1:L,1) = app.IMUData(tStart:tEnd,6);  
                gyroData(1:L,2) = app.IMUData(tStart:tEnd,5);  
                gyroData(1:L,3) = -app.IMUData(tStart:tEnd,7);      
            
            
            % 对3个轴的陀螺进行原始数据绘制
            value = app.GyroCheckBox.Value;
            if value == 1
                if isempty(app.IMUData)
                    disp('>------警告！！IMU数据为空！');
                    return;
                end
                fs = app.Fs;   % 采样频率
                t = (0:L-1).*(1/fs);
                figure;
                subplot(1,3,1);
                plot(t,gyroData(:,1));
                xlabel('time(s)');
                ylabel('rad/s');
                title('Gyro X');       
                subplot(1,3,2);
                plot(t,gyroData(:,2));
                xlabel('time(s)');
                ylabel('rad/s');
                title('Gyro Y'); 
                subplot(1,3,3);
                plot(t,gyroData(:,3));
                xlabel('time(s)');
                ylabel('rad/s');
                title('Gyro Z');                 
            end
            
            % 对3个轴的 加计 进行原始数据绘制
            value = app.AccelCheckBox.Value;
            if value == 1
                if isempty(app.IMUData)
                    disp('>------警告！！IMU数据为空！');
                    return;
                end
                fs = app.Fs;   % 采样频率
                t = (0:L-1).*(1/fs);
                figure;
                subplot(1,3,1);
                plot(t,accData(:,1));
                xlabel('time(s)');
                ylabel('g');
                title('Acc X');       
                subplot(1,3,2);
                plot(t,accData(:,2));
                xlabel('time(s)');
                ylabel('g');
                title('Acc Y'); 
                subplot(1,3,3);
                plot(t,accData(:,3));
                xlabel('time(s)');
                ylabel('g');
                title('Acc Z');                 
            end                        
        end

        % Button pushed function: Button_SimulationData
        function Button_SimulationDataPushed(app, event)
            % 制作仿真数据
            fs = app.Fs;
            time = app.eapp.EditField_SimulationTime.value;
            L = fs*time;
            
            % 陀螺的均值
            gyroMean = [-0.001593841743070  -0.001105529186918  -0.000790983464523];  % 取一次静止实验的均值 ADI 单位 rad/s
            % 加计的均值
            accelMean = [-0.002490064424045   0.002279209002395   0.999336524737357]; % ADI 单位g
            % 陀螺的噪声 均方差
            gyroNoise = app.EditField_GyroNoise.Value * (pi/180/3600);   % 单位 rad/s
            % 陀螺的漂移 噪声 均方差
            gyroDriftNoise = app.EditField_GyroDriftNoise.Value * (pi/180/3600);   % 单位 rad/s
            % 加计的噪声 均方差
            accelNoise = app.EditField_AccNoise.Value /1000;    % 单位 g
            
            % 生成仿真数据
                
        end

        % Button pushed function: Button_Initial_Attitude
        function Button_Initial_AttitudePushed(app, event)
            % 获取初始姿态的相关信息
            L = app.EditField_Initial_Time.Value*app.Fs;
                tStart = app.tStartEditField.Value;
                tEnd = app.tEndEditField.Value;
                if tEnd == 0
                    tEnd = L;
                else
                    tEnd = tEnd * app.Fs;
                    if tEnd >= L
                        tEnd = L;
                    end
                end
                if tStart == 0
                    tStart = 1;
                else
                    tStart = tStart * app.Fs;        
                    if tStart >= L
                        tStart = 1;
                    end
                end                
                if tEnd <= tStart
                    disp('>---警告：解算时间设置错误！');
                    return;
                else
                    L = tEnd - tStart + 1;
                end
                
                % 获取原始数据，这里注意 要将 ENU 坐标系 转到 NED
                gyroData = zeros(L,3);
                accData = zeros(L,3);
                %   坐标系  NED 和 前右下   加速度计的单位为g
                accData(1:L,1) = app.IMUData(tStart:tEnd,3);  
                accData(1:L,2) = app.IMUData(tStart:tEnd,2);  
                accData(1:L,3) = -app.IMUData(tStart:tEnd,4); 

                gyroData(1:L,1) = app.IMUData(tStart:tEnd,6);  
                gyroData(1:L,2) = app.IMUData(tStart:tEnd,5);  
                gyroData(1:L,3) = -app.IMUData(tStart:tEnd,7);  
             
            % 获取初始化零偏
            app.initGyroBias = mean(gyroData);
            % 获取初始零偏的均方差
            app.initGyroBias_ErrRMS = sqrt(var(gyroData));
            
            % 获取初始姿态
            magnetic = [1,0,0]; 
            accGravity = - mean(accData); 
            q = ecompass(accGravity,magnetic);   
            if parts(q) < 0
                q = -q;
            end 
            app.initAttitude = euler(q, 'ZYX', 'frame');
            
            %获取初始姿态的方差
            tmpAttitude = zeros(L,3);
            for i = 1:L
                q = ecompass(-accData(i,:),magnetic); 
                if parts(q) < 0
                    q = -q;
                end
                tmpAttitude(i,:) = euler(q, 'ZYX', 'frame');
            end
            app.initAttitude_ErrRMS = sqrt(var(tmpAttitude));
            
            app.XEditField_InitailAtt.Value = app.initAttitude(1,3)*180/pi;
            app.YEditField_InitailAtt.Value = app.initAttitude(1,2)*180/pi;
            app.ZEditField_InitailAtt.Value = app.initAttitude(1,1)*180/pi;
            
            app.XEditField_InitailAttRMS.Value = app.initAttitude_ErrRMS(1,3)*180/pi;
            app.YEditField_InitailAttRMS.Value = app.initAttitude_ErrRMS(1,2)*180/pi;
            app.ZEditField_InitailAttRMS.Value = app.initAttitude_ErrRMS(1,1)*180/pi;
            
            app.XEditField_InitailGyroBias.Value = app.initGyroBias(1,1)*180/pi*3600;
            app.YEditField_InitailGyroBias.Value = app.initGyroBias(1,2)*180/pi*3600;
            app.ZEditField_InitailGyroBias.Value = app.initGyroBias(1,3)*180/pi*3600;
            
            app.XEditField_InitailBiasRMS.Value = app.initGyroBias_ErrRMS(1,1)*180/pi*3600;
            app.YEditField_InitailBiasRMS.Value = app.initGyroBias_ErrRMS(1,2)*180/pi*3600;
            app.ZEditField_InitailBiasRMS.Value = app.initGyroBias_ErrRMS(1,3)*180/pi*3600;
            
            disp(['陀螺初始零偏 X Y Z: ',num2str(app.initGyroBias.*(180/pi*3600)),' 度/h']);
            disp(['陀螺零偏方差 X Y Z: ',num2str(app.initGyroBias_ErrRMS.*(180/pi*3600)),' 度/h']);
            
            disp(['陀螺初始姿态 X Y Z: ',num2str(app.initAttitude.*(180/pi)),' 度']);
            disp(['陀螺姿态方差 X Y Z: ',num2str(app.initAttitude_ErrRMS.*(180/pi)),' 度']);
        end

        % Value changed function: XEditField_InitailAtt
        function XEditField_InitailAttValueChanged(app, event)
            value = app.XEditField_InitailAtt.Value;
            app.initAttitude(1,3) = value*(pi/180);
        end

        % Value changed function: YEditField_InitailAtt
        function YEditField_InitailAttValueChanged(app, event)
            value = app.YEditField_InitailAtt.Value;
            app.initAttitude(1,2) = value*(pi/180);
        end

        % Value changed function: ZEditField_InitailAtt
        function ZEditField_InitailAttValueChanged(app, event)
            value = app.ZEditField_InitailAtt.Value;
            app.initAttitude(1,1) = value*(pi/180);
        end

        % Value changed function: XEditField_InitailAttRMS
        function XEditField_InitailAttRMSValueChanged(app, event)
            value = app.XEditField_InitailAttRMS.Value;
            app.initAttitude_ErrRMS(1,3) = value*(pi/180);
        end

        % Value changed function: YEditField_InitailAttRMS
        function YEditField_InitailAttRMSValueChanged(app, event)
            value = app.YEditField_InitailAttRMS.Value;
            app.initAttitude_ErrRMS(1,2) = value*(pi/180);
        end

        % Value changed function: ZEditField_InitailAttRMS
        function ZEditField_InitailAttRMSValueChanged(app, event)
            value = app.ZEditField_InitailAttRMS.Value;
            app.initAttitude_ErrRMS(1,1) = value*(pi/180);
        end

        % Value changed function: XEditField_InitailGyroBias
        function XEditField_InitailGyroBiasValueChanged(app, event)
            value = app.XEditField_InitailGyroBias.Value;
            app.initGyroBias(1,1) = value*(pi/180/3600);
        end

        % Value changed function: YEditField_InitailGyroBias
        function YEditField_InitailGyroBiasValueChanged(app, event)
            value = app.YEditField_InitailGyroBias.Value;
            app.initGyroBias(1,2) = value*(pi/180/3600);
        end

        % Value changed function: ZEditField_InitailGyroBias
        function ZEditField_InitailGyroBiasValueChanged(app, event)
            value = app.ZEditField_InitailGyroBias.Value;
            app.initGyroBias(1,3) = value*(pi/180/3600);
        end

        % Value changed function: XEditField_InitailBiasRMS
        function XEditField_InitailBiasRMSValueChanged(app, event)
            value = app.XEditField_InitailBiasRMS.Value;
            app.initGyroBias_ErrRMS(1,1) = value*(pi/180/3600);
        end

        % Value changed function: YEditField_InitailBiasRMS
        function YEditField_InitailBiasRMSValueChanged(app, event)
            value = app.YEditField_InitailBiasRMS.Value;
            app.initGyroBias_ErrRMS(1,2) = value*(pi/180/3600);
        end

        % Value changed function: ZEditField_InitailBiasRMS
        function ZEditField_InitailBiasRMSValueChanged(app, event)
            value = app.ZEditField_InitailBiasRMS.Value;
            app.initGyroBias_ErrRMS(1,3) = value*(pi/180/3600);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_DataOpen = uipanel(app.UIFigure);
            app.Panel_DataOpen.Title = '1.读取数据';
            app.openIMUData = uibutton(app.Panel_DataOpen, 'push');
            app.openIMUData.ButtonPushedFcn = createCallbackFcn(app, @openIMUDataButtonPushed, true);
            app.openIMUData.Text = '读取数据';
            app.pathIMUData = uieditfield(app.Panel_DataOpen, 'text');
            app.pathIMUData.Editable = 'off';
            app.Panel_ParaSet = uipanel(app.UIFigure);
            app.Panel_ParaSet.Title = '3.器件参数设置';
            app.dhLabel = uilabel(app.Panel_ParaSet);
            app.dhLabel.Text = '陀螺噪声(d/h)    ';
            app.EditField_GyroNoise = uieditfield(app.Panel_ParaSet, 'numeric');
            app.dhLabel_2 = uilabel(app.Panel_ParaSet);
            app.dhLabel_2.Text = '陀螺漂移噪声(d/h)';
            app.EditField_GyroDriftNoise = uieditfield(app.Panel_ParaSet, 'numeric');
            app.Label_2 = uilabel(app.Panel_ParaSet);
            app.Label_2.Text = '线加速度衰减因子(0,1)';
            app.EditField_LineAccDecayFactor = uieditfield(app.Panel_ParaSet, 'numeric');
            app.EditField_LineAccDecayFactor.Value = 0.5;
            app.Label_3 = uilabel(app.Panel_ParaSet);
            app.Label_3.Text = '磁干扰衰减因子(0 ,1)';
            app.EditField_MagDisturDecayFactor = uieditfield(app.Panel_ParaSet, 'numeric');
            app.EditField_MagDisturDecayFactor.Value = 0.5;
            app.mgLabel = uilabel(app.Panel_ParaSet);
            app.mgLabel.Text = '加速度计噪声(mg)';
            app.EditField_AccNoise = uieditfield(app.Panel_ParaSet, 'numeric');
            app.EditField_AccNoise.Tooltip = {''};
            app.uTLabel_2 = uilabel(app.Panel_ParaSet);
            app.uTLabel_2.Text = '磁强计噪声(uT)';
            app.EditField_MagNoise = uieditfield(app.Panel_ParaSet, 'numeric');
            app.EditField_MagNoise.Tooltip = {''};
            app.ms2Label = uilabel(app.Panel_ParaSet);
            app.ms2Label.Text = '线加速度噪声(m/s2)';
            app.EditField_LineAccNoise = uieditfield(app.Panel_ParaSet, 'numeric');
            app.uTLabel = uilabel(app.Panel_ParaSet);
            app.uTLabel.Text = '磁干扰噪声(uT)';
            app.EditField_MagDisturNoise = uieditfield(app.Panel_ParaSet, 'numeric');
            app.Button_SimulationData = uibutton(app.Panel_ParaSet, 'push');
            app.Button_SimulationData.ButtonPushedFcn = createCallbackFcn(app, @Button_SimulationDataPushed, true);
            app.Button_SimulationData.Text = '制作仿真数据';
            app.sLabel_2 = uilabel(app.Panel_ParaSet);
            app.sLabel_2.Text = '时长(s)';
            app.EditField_SimulationTime = uieditfield(app.Panel_ParaSet, 'numeric');
            app.EditField_SimulationTime.Value = 600;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '姿态解算方法验证';
            app.Panel_InitialSetting = uipanel(app.UIFigure);
            app.Panel_InitialSetting.Title = '4.初始参数设定[注:初始姿态不设定,自对准]';
            app.CheckBox_InitialYaw = uicheckbox(app.Panel_InitialSetting);
            app.CheckBox_InitialYaw.ValueChangedFcn = createCallbackFcn(app, @CheckBox_InitialYawValueChanged, true);
            app.CheckBox_InitialYaw.Text = '初始航向Z(度)';
            app.EditField_InitialYaw = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialYaw.Editable = 'off';
            app.CheckBox_InitialPitchRoll = uicheckbox(app.Panel_InitialSetting);
            app.CheckBox_InitialPitchRoll.ValueChangedFcn = createCallbackFcn(app, @CheckBox_InitialPitchRollValueChanged, true);
            app.CheckBox_InitialPitchRoll.Text = '初始横滚X(度)';
            app.EditField_InitialRoll = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRoll.Editable = 'off';
            app.CheckBox_InitialRMS_Attitude = uicheckbox(app.Panel_InitialSetting);
            app.CheckBox_InitialRMS_Attitude.Text = '初始姿态误差均方差(度)';
            app.CheckBox_InitialRMS_GyroDrift = uicheckbox(app.Panel_InitialSetting);
            app.CheckBox_InitialRMS_GyroDrift.Text = '初始陀螺零偏误差均方差(度/s)';
            app.CheckBox_InitialRMS_LineAcc = uicheckbox(app.Panel_InitialSetting);
            app.CheckBox_InitialRMS_LineAcc.Text = '初始线加速度误差均方差(m/s2)';
            app.CheckBox_InitialRMS_MagDistur = uicheckbox(app.Panel_InitialSetting);
            app.CheckBox_InitialRMS_MagDistur.Text = '初始磁干扰误差均方差(uT)';
            app.YLabel = uilabel(app.Panel_InitialSetting);
            app.YLabel.Text = '初始俯仰Y(度)';
            app.EditField_InitialPitch = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialPitch.Editable = 'off';
            app.XLabel = uilabel(app.Panel_InitialSetting);
            app.XLabel.Text = 'X';
            app.EditField_InitialRMS_Roll = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_Roll.Editable = 'off';
            app.EditField_InitialRMS_Roll.Value = 0.002;
            app.YLabel_2 = uilabel(app.Panel_InitialSetting);
            app.YLabel_2.Text = 'Y';
            app.EditField_InitialRMS_Pitch = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_Pitch.Editable = 'off';
            app.EditField_InitialRMS_Pitch.Value = 0.002;
            app.ZLabel = uilabel(app.Panel_InitialSetting);
            app.ZLabel.Text = 'Z';
            app.EditField_InitialRMS_Yaw = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_Yaw.Editable = 'off';
            app.EditField_InitialRMS_Yaw.Value = 0.002;
            app.XLabel_2 = uilabel(app.Panel_InitialSetting);
            app.XLabel_2.Text = 'X';
            app.EditField_InitialRMS_GyroDrift_X = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_GyroDrift_X.Editable = 'off';
            app.EditField_InitialRMS_GyroDrift_X.Value = 0.25;
            app.YLabel_3 = uilabel(app.Panel_InitialSetting);
            app.YLabel_3.Text = 'Y';
            app.EditField_InitialRMS_GyroDrift_Y = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_GyroDrift_Y.Editable = 'off';
            app.EditField_InitialRMS_GyroDrift_Y.Value = 0.25;
            app.ZLabel_2 = uilabel(app.Panel_InitialSetting);
            app.ZLabel_2.Text = 'Z';
            app.EditField_InitialRMS_GyroDrift_Z = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_GyroDrift_Z.Editable = 'off';
            app.EditField_InitialRMS_GyroDrift_Z.Value = 0.25;
            app.XLabel_3 = uilabel(app.Panel_InitialSetting);
            app.XLabel_3.Text = 'X';
            app.EditField_InitialRMS_LineAcc_X = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_LineAcc_X.Editable = 'off';
            app.EditField_InitialRMS_LineAcc_X.Value = 0.001;
            app.YLabel_4 = uilabel(app.Panel_InitialSetting);
            app.YLabel_4.Text = 'Y';
            app.EditField_InitialRMS_LineAcc_Y = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_LineAcc_Y.Editable = 'off';
            app.EditField_InitialRMS_LineAcc_Y.Value = 0.001;
            app.ZLabel_3 = uilabel(app.Panel_InitialSetting);
            app.ZLabel_3.Text = 'Z';
            app.EditField_InitialRMS_LineAcc_Z = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_LineAcc_Z.Editable = 'off';
            app.EditField_InitialRMS_LineAcc_Z.Value = 0.001;
            app.XLabel_4 = uilabel(app.Panel_InitialSetting);
            app.XLabel_4.Text = 'X';
            app.EditField_InitialRMS_MagDistur_X = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_MagDistur_X.Editable = 'off';
            app.YLabel_5 = uilabel(app.Panel_InitialSetting);
            app.YLabel_5.Text = 'Y';
            app.EditField_InitialRMS_MagDistur_Y = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_MagDistur_Y.Editable = 'off';
            app.ZLabel_4 = uilabel(app.Panel_InitialSetting);
            app.ZLabel_4.Text = 'Z';
            app.EditField_InitialRMS_MagDistur_Z = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_InitialRMS_MagDistur_Z.Editable = 'off';
            app.HzLabel = uilabel(app.Panel_InitialSetting);
            app.HzLabel.Text = '采样(Hz)';
            app.EditField_Initial_Fs = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_Initial_Fs.Value = 200;
            app.KFLabel = uilabel(app.Panel_InitialSetting);
            app.KFLabel.Text = 'KF周期';
            app.EditField_Initial_KFTime = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_Initial_KFTime.ValueChangedFcn = createCallbackFcn(app, @EditField_Initial_KFTimeValueChanged, true);
            app.EditField_Initial_KFTime.Value = 1;
            app.Button_Initial_Attitude = uibutton(app.Panel_InitialSetting, 'push');
            app.Button_Initial_Attitude.ButtonPushedFcn = createCallbackFcn(app, @Button_Initial_AttitudePushed, true);
            app.Button_Initial_Attitude.Text = '姿态初始化';
            app.sLabel = uilabel(app.Panel_InitialSetting);
            app.sLabel.Text = '初始化(s)';
            app.EditField_Initial_Time = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.EditField_Initial_Time.Value = 10;
            app.XEditFieldLabel = uilabel(app.Panel_InitialSetting);
            app.XEditFieldLabel.Text = '初始姿态(度)_X';
            app.XEditField_InitailAtt = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.XEditField_InitailAtt.ValueChangedFcn = createCallbackFcn(app, @XEditField_InitailAttValueChanged, true);
            app.YEditFieldLabel = uilabel(app.Panel_InitialSetting);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField_InitailAtt = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.YEditField_InitailAtt.ValueChangedFcn = createCallbackFcn(app, @YEditField_InitailAttValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.Panel_InitialSetting);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField_InitailAtt = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.ZEditField_InitailAtt.ValueChangedFcn = createCallbackFcn(app, @ZEditField_InitailAttValueChanged, true);
            app.RMS_XLabel = uilabel(app.Panel_InitialSetting);
            app.RMS_XLabel.Text = '姿态RMS(度)_X';
            app.XEditField_InitailAttRMS = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.XEditField_InitailAttRMS.ValueChangedFcn = createCallbackFcn(app, @XEditField_InitailAttRMSValueChanged, true);
            app.YEditFieldLabel_2 = uilabel(app.Panel_InitialSetting);
            app.YEditFieldLabel_2.Text = 'Y';
            app.YEditField_InitailAttRMS = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.YEditField_InitailAttRMS.ValueChangedFcn = createCallbackFcn(app, @YEditField_InitailAttRMSValueChanged, true);
            app.ZEditFieldLabel_2 = uilabel(app.Panel_InitialSetting);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.ZEditField_InitailAttRMS = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.ZEditField_InitailAttRMS.ValueChangedFcn = createCallbackFcn(app, @ZEditField_InitailAttRMSValueChanged, true);
            app.h_XLabel = uilabel(app.Panel_InitialSetting);
            app.h_XLabel.Text = '初始零偏(度/h)_X';
            app.XEditField_InitailGyroBias = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.XEditField_InitailGyroBias.ValueChangedFcn = createCallbackFcn(app, @XEditField_InitailGyroBiasValueChanged, true);
            app.YEditFieldLabel_3 = uilabel(app.Panel_InitialSetting);
            app.YEditFieldLabel_3.Text = 'Y';
            app.YEditField_InitailGyroBias = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.YEditField_InitailGyroBias.ValueChangedFcn = createCallbackFcn(app, @YEditField_InitailGyroBiasValueChanged, true);
            app.ZEditFieldLabel_3 = uilabel(app.Panel_InitialSetting);
            app.ZEditFieldLabel_3.Text = 'Z';
            app.ZEditField_InitailGyroBias = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.ZEditField_InitailGyroBias.ValueChangedFcn = createCallbackFcn(app, @ZEditField_InitailGyroBiasValueChanged, true);
            app.RMSh_XLabel = uilabel(app.Panel_InitialSetting);
            app.RMSh_XLabel.Text = '零偏RMS(度/h)_X';
            app.XEditField_InitailBiasRMS = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.XEditField_InitailBiasRMS.ValueChangedFcn = createCallbackFcn(app, @XEditField_InitailBiasRMSValueChanged, true);
            app.YEditFieldLabel_4 = uilabel(app.Panel_InitialSetting);
            app.YEditFieldLabel_4.Text = 'Y';
            app.YEditField_InitailBiasRMS = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.YEditField_InitailBiasRMS.ValueChangedFcn = createCallbackFcn(app, @YEditField_InitailBiasRMSValueChanged, true);
            app.ZEditFieldLabel_4 = uilabel(app.Panel_InitialSetting);
            app.ZEditFieldLabel_4.Text = 'Z';
            app.ZEditField_InitailBiasRMS = uieditfield(app.Panel_InitialSetting, 'numeric');
            app.ZEditField_InitailBiasRMS.ValueChangedFcn = createCallbackFcn(app, @ZEditField_InitailBiasRMSValueChanged, true);
            app.ButtonGroup_DeviceChoose = uibuttongroup(app.UIFigure);
            app.ButtonGroup_DeviceChoose.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_DeviceChooseSelectionChanged, true);
            app.ButtonGroup_DeviceChoose.Title = '2.器件选择';
            app.Button_MPU_L = uiradiobutton(app.ButtonGroup_DeviceChoose);
            app.Button_MPU_L.Text = 'MPU_L';
            app.Button_MPU_R = uiradiobutton(app.ButtonGroup_DeviceChoose);
            app.Button_MPU_R.Text = 'MPU_R';
            app.Button_ADI_L = uiradiobutton(app.ButtonGroup_DeviceChoose);
            app.Button_ADI_L.Text = 'ADI_L';
            app.Button_ADI_R = uiradiobutton(app.ButtonGroup_DeviceChoose);
            app.Button_ADI_R.Text = 'ADI_R';
            app.Button_ADI_R.Value = true;
            app.ButtonGroup_AlgorithmChoose = uibuttongroup(app.UIFigure);
            app.ButtonGroup_AlgorithmChoose.Title = '5.滤波算法选择';
            app.KF9 = uiradiobutton(app.ButtonGroup_AlgorithmChoose);
            app.KF9.Text = 'GyroAcc9KF';
            app.KF9.Value = true;
            app.KF12 = uiradiobutton(app.ButtonGroup_AlgorithmChoose);
            app.KF12.Text = 'GyroAccMag12KF';
            app.Complement = uiradiobutton(app.ButtonGroup_AlgorithmChoose);
            app.Complement.Text = 'Complement';
            app.KF6 = uiradiobutton(app.ButtonGroup_AlgorithmChoose);
            app.KF6.Text = 'GyroAcc6KF';
            app.Panel_Draw = uipanel(app.UIFigure);
            app.Panel_Draw.Title = '7.数据绘制';
            app.CheckBox_DrawNow = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawNow.Text = 'Now_k';
            app.CheckBox_DrawOld1 = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawOld1.Text = 'Old1_b';
            app.CheckBox_DrawOld2 = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawOld2.Text = 'Old2_r';
            app.CheckBox_DrawData_Roll = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawData_Roll.Text = 'Roll';
            app.CheckBox_DrawData_Roll.Value = true;
            app.CheckBox_DrawData_Pitch = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawData_Pitch.Text = 'PItch';
            app.CheckBox_DrawData_Pitch.Value = true;
            app.CheckBox_DrawData_Yaw = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawData_Yaw.Text = 'Yaw';
            app.CheckBox_DrawData_Yaw.Value = true;
            app.Label_5 = uilabel(app.Panel_Draw);
            app.Label_5.Text = '选择所要绘制的姿态';
            app.Label_6 = uilabel(app.Panel_Draw);
            app.Label_6.Text = '选择所要对比的数据';
            app.Button_Draw = uibutton(app.Panel_Draw, 'push');
            app.Button_Draw.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawPushed, true);
            app.Button_Draw.Text = '绘制';
            app.Label_7 = uilabel(app.Panel_Draw);
            app.Label_7.Text = '选择绘制的中间量';
            app.CheckBox_DrawData_Xk = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawData_Xk.Text = 'Xk';
            app.CheckBox_DrawData_Xk.Value = true;
            app.CheckBox_DrawData_Bias = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawData_Bias.Text = 'BiasG';
            app.CheckBox_DrawData_Bias.Value = true;
            app.CheckBox_DrawData_LineAcc = uicheckbox(app.Panel_Draw);
            app.CheckBox_DrawData_LineAcc.Text = 'LineAcc';
            app.CheckBox_DrawData_LineAcc.Value = true;
            app.Label_8 = uilabel(app.Panel_Draw);
            app.Label_8.Text = '绘制原始数据';
            app.Button_DrawOriginal = uibutton(app.Panel_Draw, 'push');
            app.Button_DrawOriginal.ButtonPushedFcn = createCallbackFcn(app, @Button_DrawOriginalPushed, true);
            app.Button_DrawOriginal.Text = '绘制原始数据';
            app.GyroCheckBox = uicheckbox(app.Panel_Draw);
            app.GyroCheckBox.Text = 'Gyro';
            app.AccelCheckBox = uicheckbox(app.Panel_Draw);
            app.AccelCheckBox.Text = 'Accel';
            app.Panel_SaveData = uipanel(app.UIFigure);
            app.Panel_SaveData.Title = '6.计算并保存';
            app.Button_Begin = uibutton(app.Panel_SaveData, 'push');
            app.Button_Begin.ButtonPushedFcn = createCallbackFcn(app, @Button_BeginPushed, true);
            app.Button_Begin.Text = '解算';
            app.Button_SaveOld1 = uibutton(app.Panel_SaveData, 'push');
            app.Button_SaveOld1.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveOld1Pushed, true);
            app.Button_SaveOld1.Text = '存1';
            app.Button_ClearOld1 = uibutton(app.Panel_SaveData, 'push');
            app.Button_ClearOld1.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearOld1Pushed, true);
            app.Button_ClearOld1.Text = '清1';
            app.CheckBox_DataStateNow = uicheckbox(app.Panel_SaveData);
            app.CheckBox_DataStateNow.Enable = 'off';
            app.CheckBox_DataStateNow.Text = 'NowData';
            app.CheckBox_DataStateOld1 = uicheckbox(app.Panel_SaveData);
            app.CheckBox_DataStateOld1.Enable = 'off';
            app.CheckBox_DataStateOld1.Text = 'Old1Data';
            app.Button_SaveOld2 = uibutton(app.Panel_SaveData, 'push');
            app.Button_SaveOld2.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveOld2Pushed, true);
            app.Button_SaveOld2.Text = '存2';
            app.Button_ClearOld2 = uibutton(app.Panel_SaveData, 'push');
            app.Button_ClearOld2.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearOld2Pushed, true);
            app.Button_ClearOld2.Text = '清2';
            app.CheckBox_DataStateOld2 = uicheckbox(app.Panel_SaveData);
            app.CheckBox_DataStateOld2.Enable = 'off';
            app.CheckBox_DataStateOld2.Text = 'Old2Data';
            app.tStartsEditFieldLabel = uilabel(app.Panel_SaveData);
            app.tStartsEditFieldLabel.Text = 'tStart(s)';
            app.tStartEditField = uieditfield(app.Panel_SaveData, 'numeric');
            app.tEndsEditFieldLabel = uilabel(app.Panel_SaveData);
            app.tEndsEditFieldLabel.Text = 'tEnd(s)';
            app.tEndEditField = uieditfield(app.Panel_SaveData, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AttitudeAlgorithm6KF_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end